classdef correlation_verification < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        correlation_app  matlab.ui.Figure
        WS_Height        matlab.ui.control.DropDown
        Label            matlab.ui.control.Label
        Label_2          matlab.ui.control.Label
        WS_Diff          matlab.ui.control.EditField
        Panel            matlab.ui.container.Panel
        UITable          matlab.ui.control.Table
        WS_Low           matlab.ui.control.DropDown
        WD_Height        matlab.ui.control.DropDown
        Label_3          matlab.ui.control.Label
        Label_4          matlab.ui.control.Label
        WD_Diff          matlab.ui.control.EditField
        WD_Low           matlab.ui.control.DropDown
        Search           matlab.ui.control.Button
        Inter_P1         matlab.ui.control.Button
        Data_Update      matlab.ui.control.Button
        Close            matlab.ui.control.Button
        WS_Lable         matlab.ui.control.Label
        WD_Lable         matlab.ui.control.Label
        Error_WS_Lable   matlab.ui.control.Label
        Error_WD_Lable   matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp       % 主程序
        WS
        WD
        Temp
        BP
        WS_size
        WD_size
        Temp_size
        BP_size
        WS_Height_Info      %避免与上面的WS_Height重复，所以用WS_Height_Info存储数据高度
        WD_Height_Info
        Temp_Height_Info
        BP_Height_Info
        All_Data
        diff_ws_data        %风速差
        diff_wd_data        %风向差
    end
    
    methods (Access = private)
        
        function Data_Setup(app)
            app.WS = app.CallingApp.WS_Hour;        %访问主程序数据
            app.WD = app.CallingApp.WD_Hour;
            app.Temp = app.CallingApp.Temp_Hour;
            app.BP = app.CallingApp.BP_Hour;
            app.WS_size = size(app.WS);
            app.WD_size = size(app.WD);
            app.Temp_size = size(app.Temp);
            app.BP_size = size(app.BP);
            app.WS_Height_Info = app.CallingApp.WS.Properties.CustomProperties.height;
            app.WD_Height_Info = app.CallingApp.WD.Properties.CustomProperties.height;
            app.Temp_Height_Info = app.CallingApp.Temp.Properties.CustomProperties.height;
            app.BP_Height_Info = app.CallingApp.BP.Properties.CustomProperties.height;
            
            app.All_Data = [app.WS{:,2:end},app.WD{:,2:end},app.Temp{:,2:end},app.BP{:,2:end}];      %拼接数据，不包含时间数据
        end
        
        function UI_Setup(app)
            app.WS_Height.Items = strcat(string(app.WS_Height_Info(2:end))," m");
            app.WS_Low.Items = strcat(string(app.WS_Height_Info(2:end))," m");
            app.WD_Height.Items = strcat(string(app.WD_Height_Info(2:end))," m");
            app.WD_Low.Items = strcat(string(app.WD_Height_Info(2:end))," m");
            try 
                app.WS_Height.Value = {"50 m"};
                app.WS_Low.Value = {"30 m"};
                app.WD_Height.Value = {"50 m"};
                app.WD_Low.Value = {"30 m"};
            catch 
                
            end
        end
        
         function Table_Setup(app)
            %*************************表格初始化*******************************%
            app.UITable.Visible = 'on';     %表格状态为可见
            app.UITable.Data = [];      %删除表格数据
            app.UITable.UserData = [];      %清空标记数据
            s = uistyle("BackgroundColor",'white');
            addStyle(app.UITable,s);
            if app.WS_size(2) > 1
                ColumnName(1:app.WS_size(2) - 1) = strcat("Speed ", string(app.WS_Height_Info(2:end)),"m");
            end
            if app.WD_size(2) > 1
                ColumnName(app.WS_size(2):app.WS_size(2) - 1 + app.WD_size(2) - 1) = strcat("Direction ",string(app.WD_Height_Info(2:end)),"m");
            end
            if app.Temp_size(2) > 1
                ColumnName(app.WS_size(2) - 1 + app.WD_size(2) : app.WS_size(2) - 1 + app.WD_size(2) - 1 + app.Temp_size(2) - 1) ...
                    = strcat("Temp ",string(app.Temp_Height_Info(2:end)),"m");
            end
            if app.BP_size(2) > 1
                ColumnName(app.WS_size(2) - 1 + app.WD_size(2) - 1 + app.Temp_size(2) ...
                    : app.WS_size(2) - 1 + app.WD_size(2) - 1 + app.Temp_size(2) - 1 + app.BP_size(2) - 1) = strcat("BP ",string(app.BP_Height_Info(2:end)),"m");
            end
            app.UITable.ColumnName = cellstr(ColumnName);
            
            %**************************数据整合*******************************%
            ws_row = [];
            wd_row = [];
            time_ws = [];
            time_wd = [];
            error_ws = [];
            error_wd = [];
            if app.WS_size(2) > 2
                [ws_row, ws_col] = Find_WS_Error(app);
                ws_col = ws_col + app.WS_size(2) - 1;   %wd_col的绝对位置为相对位置加风速数据列
                ws_row = unique(ws_row);
                error_ws = app.All_Data(ws_row,:);
                time_ws= app.WS{ws_row,1};
            end
            if app.WD_size(2) > 2
                [wd_row, wd_col] = Find_WD_Error(app);
                wd_col = wd_col + app.WS_size(2) -1 + app.WD_size(2) ;
                wd_row = unique(wd_row);
                error_wd = app.All_Data(wd_row,:);
                time_wd = app.WS{wd_row,1};
            end
            ws_row_size = size(ws_row);
            wd_row_size = size(wd_row);
            %*************获取不合理数据点****************%
            error_point = [ws_row_size(1),wd_row_size(1)];
            data = [error_ws;error_wd];
            time = [time_ws;time_wd];
            %************************************************%
            if app.WS_size(2) > 2 | app.WD_size(2) > 2
                app.UITable.Data = data;
                app.UITable.RowName = cellstr(time);
                s = uistyle('HorizontalAlignment','left');
                addStyle(app.UITable,s);
            end
            
            %不合理数据点数信息
            app.Error_WS_Lable.Text = strcat("风速不合理数据点数： ",string(2*error_point(1)));
            app.Error_WD_Lable.Text = strcat("风向不合理数据点数： ",string(2*error_point(2)));
            %标记数据
            Mark_Data(app);
         end
         
        
        function [row , col ] = Find_WS_Error(app)
            %查询风速不合理数据
            for index = 2:app.WS_size(2)
                ws_height = split(app.WS_Height.Value,' ');
                ws_low = split(app.WS_Low.Value,' ');
                if isequal(app.WS_Height_Info(index) , str2double(ws_height{1}))
                    index_Height = index;
                end
                if isequal(app.WS_Height_Info(index) , str2double(ws_low{1}))
                    index_Low = index;
                end
            end
            app.diff_ws_data = abs(app.WS{:,index_Height} - app.WS{:,index_Low});
            [row,col] = find(app.diff_ws_data > str2double(app.WS_Diff.Value));
        end
        
        
        function [row , col ] = Find_WD_Error(app)
            %查询风速不合理数据
            for index = 2:app.WD_size(2)
                wd_height = split(app.WD_Height.Value,' ');
                wd_low = split(app.WD_Low.Value,' ');
                
                if isequal(app.WD_Height_Info(index) , str2double(wd_height{1}))
                    index_Height = index;
                end
                if isequal(app.WD_Height_Info(index) , str2double(wd_low{1}))
                    index_Low = index;
                end
            end
            app.diff_wd_data= abs(app.WD{:,index_Height} - app.WD{:,index_Low});
            [row,col] = find(app.diff_wd_data > str2double(app.WD_Diff.Value));
        end
        
        function Mark_Data(app)
            %标记不合理数据
            ws_index_Height = 0;
            ws_index_Low = 0;
            wd_index_Height = 0;
            wd_index_Low = 0;
            for index = 2:app.WS_size(2)
                ws_height = split(app.WS_Height.Value,' ');
                ws_low = split(app.WS_Low.Value,' ');
                if isequal(app.WS_Height_Info(index) , str2double(ws_height{1}))
                    ws_index_Height = index - 1;        %风速数据所在列数
                end
                if isequal(app.WS_Height_Info(index) , str2double(ws_low{1}))
                    ws_index_Low = index - 1;
                end
            end
            

            for index = 2:app.WD_size(2)
                wd_height = split(app.WD_Height.Value,' ');
                wd_low = split(app.WD_Low.Value,' ');
                
                if isequal(app.WD_Height_Info(index) , str2double(wd_height{1}))
                    wd_index_Height = index - 1;
                end
                if isequal(app.WD_Height_Info(index) , str2double(wd_low{1}))
                    wd_index_Low = index - 1;
                end
            end
            
            ws_height_col = ws_index_Height;
            ws_low_col = ws_index_Low;
            wd_height_col = wd_index_Height + app.WS_size(2) - 1;
            wd_low_col = wd_index_Low + app.WS_size(2) - 1;

            %获取所选高度数据差值

            
            ws_height_data = app.UITable.Data(:,ws_height_col);
            ws_low_data = app.UITable.Data(:,ws_low_col);
            wd_height_data = app.UITable.Data(:,wd_height_col);
            wd_low_data = app.UITable.Data(:,wd_low_col);
            ws_diff = abs(ws_height_data - ws_low_data);
            wd_diff = abs(wd_height_data - wd_low_data);
            
            [mark_ws_row,mark_ws_col] = find(ws_diff>str2double(app.WS_Diff.Value));
            [mark_wd_row,mark_wd_col] = find(wd_diff>str2double(app.WD_Diff.Value));  
            mark_wd_col = mark_wd_col + app.WS_size(2) - 1;     %相对位置转为绝对位置
            s = uistyle('BackgroundColor','red');
            ws_row_size = size(mark_ws_row);
            wd_row_size = size(mark_wd_row);
            addStyle(app.UITable,s,'cell',[mark_ws_row,repelem(ws_height_col,ws_row_size(1))']);       %同时标记作差的两列数据
            addStyle(app.UITable,s,'cell',[mark_ws_row,repelem(ws_low_col,ws_row_size(1))']);
            addStyle(app.UITable,s,'cell',[mark_wd_row,repelem(wd_height_col,wd_row_size(1))']); 
            addStyle(app.UITable,s,'cell',[mark_wd_row,repelem(wd_low_col,wd_row_size(1))']); 


        end
        
        function Interpolation_Data(app)
            %线性插值
            ws_index_Height = 0;
            ws_index_Low = 0;
            wd_index_Height = 0;
            wd_index_Low = 0;
            for index = 2:app.WS_size(2)
                ws_height = split(app.WS_Height.Value,' ');
                ws_low = split(app.WS_Low.Value,' ');
                if isequal(app.WS_Height_Info(index) , str2double(ws_height{1}))
                    ws_index_Height = index - 1;
                end
                if isequal(app.WS_Height_Info(index) , str2double(ws_low{1}))
                    ws_index_Low = index - 1;      %数据在All_Data中的位置
                end
            end
              
            for index = 2:app.WD_size(2)
                wd_height = split(app.WD_Height.Value,' ');
                wd_low = split(app.WD_Low.Value,' ');
                if isequal(app.WD_Height_Info(index) , str2double(wd_height{1}))
                    wd_index_Height = index - 1 + app.WS_size(2) - 1;
                end
                if isequal(app.WD_Height_Info(index) , str2double(wd_low{1}))
                    wd_index_Low = index - 1 + app.WS_size(2) - 1;
                end
            end
            %获取标记数据
            if app.WS_size(2) > 2
                height_ws_data = app.All_Data(:,ws_index_Height);
                low_ws_data = app.All_Data(:,ws_index_Low);
                [ws_row,ws_col] = Find_WS_Error(app);
                pre_height_ws_data = circshift(height_ws_data,-1);
                next_height_ws_data = circshift(height_ws_data,1);
                pre_low_ws_data = circshift(low_ws_data,-1);
                next_low_ws_data = circshift(low_ws_data,1);
                for index = ws_row'
                    app.All_Data(index,ws_index_Height) = interp1([pre_height_ws_data(index),next_height_ws_data(index)],1.5);
                    app.All_Data(index,ws_index_Low) = interp1([pre_low_ws_data(index),next_low_ws_data(index)],1.5);
                end
            end
            if app.WD_size(2) > 2
                height_wd_data = app.All_Data(:,wd_index_Height);
                low_wd_data = app.All_Data(:,wd_index_Low);
                [wd_row,wd_col] = Find_WD_Error(app);       %不合理数据的位置
                pre_height_wd_data = circshift(height_wd_data,-1);
                next_height_wd_data = circshift(height_wd_data,1);
                pre_low_wd_data = circshift(low_wd_data,-1);
                next_low_wd_data = circshift(low_wd_data,1);
                for index = wd_row'
                    app.All_Data(index,wd_index_Height) = interp1([pre_height_wd_data(index),next_height_wd_data(index)],1.5);
                    app.All_Data(index,wd_index_low) = interp1([pre_low_wd_data(index),next_low_wd_data(index)],1.5);
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
        app.CallingApp = mainapp;       %绑定主程序
        app.UITable.Visible = 'off';    %隐藏表格
        Data_Setup(app);
        UI_Setup(app);
        
        
        end

        % Button pushed function: Search
        function SearchButtonPushed(app, event)
            Table_Setup(app);
        end

        % Button pushed function: Inter_P1
        function Inter_P1ButtonPushed(app, event)
            %对所选高度不合理数据进行线性插值
        Interpolation_Data(app);
            
            
        end

        % Button pushed function: Data_Update
        function Data_UpdateButtonPushed(app, event)
            ws_size = size(app.WS);
            wd_size = size(app.WD);
            app.WS(:,2:ws_size(2)) = array2table(app.All_Data(:,1:ws_size(2) - 1));
            app.WD(:,2:wd_size(2)) = array2table(app.All_Data(:,ws_size(2) : ws_size(2) + wd_size(2) - 1 - 1));
            Table_Setup(app);       %将更新后的数据绘制于表格中
        end

        % Button pushed function: Close
        function CloseButtonPushed(app, event)
            %关闭子程序
            app.CallingApp.WS_Hour = app.WS;
            app.CallingApp.WD_Hour = app.WD;
            delete(app.correlation_app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.correlation_app = uifigure('Visible', 'off');
            app.correlation_app.Name = '相关性验证';
            app.WS_Height = uidropdown(app.correlation_app);
            app.WS_Height.Items = {''};
            app.WS_Height.Value = '';
            app.Label = uilabel(app.correlation_app);
            app.Label.Text = '/';
            app.Label_2 = uilabel(app.correlation_app);
            app.Label_2.Text = '高度小时平均风速差值小于';
            app.WS_Diff = uieditfield(app.correlation_app, 'text');
            app.WS_Diff.Value = '2';
            app.Panel = uipanel(app.correlation_app);
            app.UITable = uitable(app.Panel);
            app.UITable.ColumnName = {''; ''; ''; ''};
            app.UITable.RowName = {};
            app.WS_Low = uidropdown(app.correlation_app);
            app.WS_Low.Items = {''};
            app.WS_Low.Value = '';
            app.WD_Height = uidropdown(app.correlation_app);
            app.WD_Height.Items = {''};
            app.WD_Height.Value = '';
            app.Label_3 = uilabel(app.correlation_app);
            app.Label_3.Text = '/';
            app.Label_4 = uilabel(app.correlation_app);
            app.Label_4.Text = '高度小时平均风向差值小于';
            app.WD_Diff = uieditfield(app.correlation_app, 'text');
            app.WD_Diff.Value = '25';
            app.WD_Low = uidropdown(app.correlation_app);
            app.WD_Low.Items = {''};
            app.WD_Low.Value = '';
            app.Search = uibutton(app.correlation_app, 'push');
            app.Search.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.Search.Text = '查        询';
            app.Inter_P1 = uibutton(app.correlation_app, 'push');
            app.Inter_P1.ButtonPushedFcn = createCallbackFcn(app, @Inter_P1ButtonPushed, true);
            app.Inter_P1.Text = '线性插值';
            app.Data_Update = uibutton(app.correlation_app, 'push');
            app.Data_Update.ButtonPushedFcn = createCallbackFcn(app, @Data_UpdateButtonPushed, true);
            app.Data_Update.Text = '数据更新';
            app.Close = uibutton(app.correlation_app, 'push');
            app.Close.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.Close.Text = '完        成';
            app.WS_Lable = uilabel(app.correlation_app);
            app.WS_Lable.Text = 'm/s';
            app.WD_Lable = uilabel(app.correlation_app);
            app.WD_Lable.Text = '°';
            app.Error_WS_Lable = uilabel(app.correlation_app);
            app.Error_WS_Lable.Text = '';
            app.Error_WD_Lable = uilabel(app.correlation_app);
            app.Error_WD_Lable.Text = '';
            % Show the figure after all components are created
            app.correlation_app.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = correlation_verification(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.correlation_app)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.correlation_app)
        end
    end
end