classdef data_interpolation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        interpolation_app    matlab.ui.Figure
        UIAxes               matlab.ui.control.UIAxes
        UITable              matlab.ui.control.Table
        Panel                matlab.ui.container.Panel
        Label                matlab.ui.control.Label
        WS_Height            matlab.ui.control.DropDown
        Label_2              matlab.ui.control.Label
        Inter_Type           matlab.ui.control.DropDown
        Label_3              matlab.ui.control.Label
        Reference_Height     matlab.ui.control.DropDown
        Caculate             matlab.ui.control.Button
        Data_Update          matlab.ui.control.Button
        Finish               matlab.ui.control.Button
        Start_Index          matlab.ui.control.Button
        Start_Index_Value    matlab.ui.control.Label
        End_Index            matlab.ui.control.Button
        End_Index_Value      matlab.ui.control.Label
        Delete_Choice_Data   matlab.ui.control.Button
        Data_Integrity_Rate  matlab.ui.control.Table
    end

    
    properties (Access = private)
        CallingApp   % 主程序
        WS
        WD
        Temp
        BP
        WS_Height_Info
        WD_Height_Info
        Temp_Height_Info
        BP_Height_Info
        WS_size
        WD_size
        Temp_size
        BP_size
        row     %要进行数据插补的行、列
        col
        po      %plot句柄
        dcm_obj
    end
    
    methods (Access = private)
        
        function Data_Setup(app)
            app.WS = app.CallingApp.WS_Hour;
            app.WD = app.CallingApp.WD_Hour;
            app.Temp = app.CallingApp.Temp_Hour;
            app.BP = app.CallingApp.BP_Hour;
            app.WS_Height_Info = app.CallingApp.WS.Properties.CustomProperties.height;
            app.WD_Height_Info = app.CallingApp.WD.Properties.CustomProperties.height;
            app.Temp_Height_Info = app.CallingApp.Temp.Properties.CustomProperties.height;
            app.BP_Height_Info = app.CallingApp.BP.Properties.CustomProperties.height;
            app.WS_size = size(app.WS);
            app.WD_size = size(app.WD);
            app.Temp_size = size(app.Temp);
            app.BP_size = size(app.BP);
        end
        
        function UI_Setup(app)
            height = app.WS_Height_Info;
            app.WS_Height.Items = strcat(string(height)," m");
            app.WS_Height.Items(1) = {'all'};
            app.Reference_Height.Items = strcat(string(height), " m");
            app.Reference_Height.Items(1) = [];
            datacursormode(app.interpolation_app,'on');
        end
        
        function Drow_Plot(app)
            %提取数据
            cla(app.UIAxes);        %清空坐标区
            time = app.WS{:,1};
            for index = 2:app.WS_size(2)
                try
                    height = split(app.WS_Height.Value," ");
                catch
                    
                end
                if isequal(app.WS_Height.Value, "all")
                    ws_data = app.WS{:,2:end};
                    labels = strcat(string(app.WS_Height_Info(2:end)), " m");
                end
                if isequal(app.WS_Height_Info(index),str2double(height(1)))
                    ws_data = app.WS{:,index};
                    labels = strcat(string(app.WS_Height_Info(index)), " m");
                end
            end
            data_size = size(ws_data);
            for index = 1:data_size(2)
                app.po = plot(app.UIAxes,time,ws_data(:,index));
                hold(app.UIAxes,'on');
                legend(app.UIAxes,labels);
            end
    end
    
        function Drow_Table(app)
            %绘制数据表格
            time = app.WS{:,1};
            for index = 2:app.WS_size(2)
                try
                    height = split(app.WS_Height.Value," ");
                catch
                    
                end
                if isequal(app.WS_Height.Value, "all")
                    ws_data = app.WS{:,2:end};
                    for column_index = 2:app.WS_size(2)
                        column(column_index - 1) = strcat("Speed ",string(app.WS_Height_Info(column_index)), " m");
                    end
                    
                end
                if isequal(app.WS_Height_Info(index),str2double(height(1)))
                    ws_data = app.WS{:,index};
                    column(1) = strcat("Speed ",string(app.WS_Height_Info(index)), " m");
                end
            end
            app.UITable.ColumnName = cellstr(column);
            app.UITable.RowName = string(time);
            app.UITable.Data = ws_data;
            s = uistyle('HorizontalAlignment','left');
            addStyle(app.UITable,s);
        end  


        function mark_row = get_mark_row(app)
            %通过MATLAB的数据游标功能获取要进行插补的数据信息
            try
                app.dcm_obj = datacursormode(app.interpolation_app);
                info_struct = getCursorInfo(app.dcm_obj);
                mark_row = info_struct.DataIndex;
            catch
                warndlg("未指定数据");
            end
        end
        
        function Mark_Data(app)
            %标记所有为NaN的数据
            [mark_row,mark_col] = find(isnan(app.UITable.Data));
            s = uistyle("BackgroundColor",'red');
            addStyle(app.UITable,s,'cell',[mark_row,mark_col]);
        end
        
        function Drow_Data_Integrity_Rate_Table(app)
            %计算数据完整率
            time = app.WS{:,1};
            time_diff = split(string(time(end) - time(1)),":");
            time_diff = str2double(time_diff);
            if time_diff(2) > 0 || time_diff(3) > 0
                data_point = time_diff(1) + 2;
            else
                data_point = time_diff(1) + 1;
            end
            for column_index = 2:app.WS_size(2)
                column(column_index - 1) = strcat("Speed ",string(app.WS_Height_Info(column_index)), " m");
                unmark_row = find(~isnan(table2array(app.WS(:,column_index))));
                row_size = size(unmark_row);
                rate_data(column_index - 1) = row_size(1) / data_point;
            end
            rate_data = round(rate_data,4)*100;
            app.Data_Integrity_Rate.Data = rate_data;
            app.Data_Integrity_Rate.ColumnName = column;
            app.Data_Integrity_Rate.RowName = "数据完整率(%)";
            s = uistyle('HorizontalAlignment','left');
            addStyle(app.UITable,s);
            
        end
        
        function Update_Table(app)
            %更新图表
            Drow_Plot(app);
            Drow_Table(app);
            Drow_Data_Integrity_Rate_Table(app);
            Mark_Data(app);
        end
        
        function Wind_Shear_Interpolation(app)
            %根据不同高度的风切变系数进行数据插补
            data_height = 0;    %数据高度
            reference_height = 0;   %参考数据高度
            data_index = 0;
            for index = 2:app.WS_size(2)
                try
                    height = split(app.WS_Height.Value," ");
                catch
                end
                if isequal(app.WS_Height.Value, "all")
                    ws_data = app.WS{:,2:end};
                    errordlg("数据选择过多，请选择单一高度数据");
                    return;
                end
                if isequal(app.WS_Height_Info(index),str2double(height(1)))
                    ws_data = app.WS{:,index};
                    data_index = index;
                    data_height = app.WS_Height_Info(index);
                end
            end
            %选择参考数据
            for index = 2:app.WS_size(2)
                try
                    height = split(app.Reference_Height.Value," ");
                catch
                end
                if isequal(app.WS_Height_Info(index),str2double(height(1)))
                    reference_data = app.WS{:,index};
                    reference_height = app.WS_Height_Info(index);
                end
            end
            mean(ws_data(~isnan(ws_data)))
            mean(reference_data(~isnan(reference_data)))
            wind_shear = Caculate_Wind_Shear(app,data_height,reference_height,...
            mean(ws_data(~isnan(ws_data))),mean(reference_data(~isnan(reference_data))));       %获取两高度之间的风切变系数
            nan_row = find(isnan(ws_data));
            ws_data(nan_row) = reference_data(nan_row).*(data_height/reference_height).^wind_shear;
            %将插补后的数据传给app.WS
            app.WS(:,data_index) = array2table(ws_data);
        end
        
        function wind_shear = Caculate_Wind_Shear(~,data_height,reference_height,ws_data,reference_data)
            %计算风切变系数
            x_data = [data_height,reference_height];
            y_data = [ws_data,reference_data];
            if x_data(1) == x_data(2)
                errordlg("参考高度与计算高度相同，请重新选择参考高度");
                return;
            end
            x_data = log(x_data);
            y_data = log(y_data);
            [xData, yData] = prepareCurveData( x_data, y_data );        %x_data为高度数据，y_data为风速数据
            ft = fittype( 'a*x + b', 'independent', 'x', 'dependent', 'y' );
            opts = fitoptions( 'Method', 'NonlinearLeastSquares' );
            [fitresult, ~] = fit( xData, yData, ft, opts );        %fitresult存储拟合结果，gof存储相关系数
            wind_shear = fitresult.a;
            
        end
        
        function Transfor_Data(app)
            %关闭数据游标，并见数据传回主程序
            app.dcm_obj.Enable = 'off';
            app.CallingApp.WS_Hour = app.WS;
            delete(app.interpolation_app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;       %绑定主程序
            Data_Setup(app);        %数据初始化
            UI_Setup(app);          %窗口初始化
            Update_Table(app);      
        end

        % Value changed function: WS_Height
        function WS_HeightValueChanged(app, event)
              Drow_Plot(app);
              Drow_Table(app);
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            app.row = indices(:,1);
            app.col = indices(:,2) + 1;     %第一列为时间数据，所以列数往后移一位
        end

        % Button pushed function: Caculate
        function CaculateButtonPushed(app, event)
            Wind_Shear_Interpolation(app);
        end

        % Button pushed function: Data_Update
        function Data_UpdateButtonPushed(app, event)
           Update_Table(app);
        end

        % Button pushed function: Finish
        function FinishButtonPushed(app, event)
        Transfor_Data(app);
        end

        % Button pushed function: Start_Index
        function Start_IndexButtonPushed(app, event)
            %当按下获取开始时间按钮时，将要删除数据的开始时间显示到start_index_value
            mark_row = get_mark_row(app);
            app.Start_Index_Value.Text = string(app.WS{mark_row,1});
        end

        % Button pushed function: End_Index
        function End_IndexButtonPushed(app, event)
            %当按下获取结束时间按钮时，将要删除数据的开始时间显示到start_index_value
            mark_row = get_mark_row(app);
            app.End_Index_Value.Text = string(app.WS{mark_row,1});
        end

        % Button pushed function: Delete_Choice_Data
        function Delete_Choice_DataButtonPushed(app, event)
            app.WS(app.row,app.col) = {NaN};        %删除表格中选择的数据
            
            for index = 2:app.WS_size(2)
                try
                    height = split(app.WS_Height.Value," ");
                catch  
                end
                
                time_str = string(app.WS{:,1});
                mark_start_row = find(time_str == app.Start_Index_Value.Text);
                mark_end_row = find(time_str == app.End_Index_Value.Text);
                if isequal(app.WS_Height.Value, "all")
                    app.WS(mark_start_row:mark_end_row,2:end) = {NaN};
                end
                if isequal(app.WS_Height_Info(index),str2double(height(1)))
                   app.WS(mark_start_row:mark_end_row,index) = {NaN};
                end
            end
            %更新图表数据
            Update_Table(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.interpolation_app = uifigure('Visible', 'off');
            app.interpolation_app.Name = '缺测数据插补';
            app.UIAxes = uiaxes(app.interpolation_app);
            title(app.UIAxes, '风速时间序列')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '风速(m/s)')
            app.UITable = uitable(app.interpolation_app);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.Panel = uipanel(app.interpolation_app);
            app.Label = uilabel(app.Panel);
            app.Label.Text = '数据高度';
            app.WS_Height = uidropdown(app.Panel);
            app.WS_Height.Items = {};
            app.WS_Height.ValueChangedFcn = createCallbackFcn(app, @WS_HeightValueChanged, true);
            app.WS_Height.Value = {};
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '插补方式';
            app.Inter_Type = uidropdown(app.Panel);
            app.Inter_Type.Items = {'风切变系数法', '数据重构法'};
            app.Inter_Type.Value = '风切变系数法';
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '参考高度';
            app.Reference_Height = uidropdown(app.Panel);
            app.Reference_Height.Items = {};
            app.Reference_Height.Value = {};
            app.Caculate = uibutton(app.Panel, 'push');
            app.Caculate.ButtonPushedFcn = createCallbackFcn(app, @CaculateButtonPushed, true);
            app.Caculate.Text = '执行插补';
            app.Data_Update = uibutton(app.Panel, 'push');
            app.Data_Update.ButtonPushedFcn = createCallbackFcn(app, @Data_UpdateButtonPushed, true);
            app.Data_Update.Text = '数据更新';
            app.Finish = uibutton(app.Panel, 'push');
            app.Finish.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.Finish.Text = '完        成';
            app.Start_Index = uibutton(app.Panel, 'push');
            app.Start_Index.ButtonPushedFcn = createCallbackFcn(app, @Start_IndexButtonPushed, true);
            app.Start_Index.Text = '捕获开始时间点';
            app.Start_Index_Value = uilabel(app.Panel);
            app.Start_Index_Value.Text = '';
            app.End_Index = uibutton(app.Panel, 'push');
            app.End_Index.ButtonPushedFcn = createCallbackFcn(app, @End_IndexButtonPushed, true);
            app.End_Index.Text = '捕获结束时间点';
            app.End_Index_Value = uilabel(app.Panel);
            app.End_Index_Value.Text = '';
            app.Delete_Choice_Data = uibutton(app.Panel, 'push');
            app.Delete_Choice_Data.ButtonPushedFcn = createCallbackFcn(app, @Delete_Choice_DataButtonPushed, true);
            app.Delete_Choice_Data.Text = '清除所选数据';
            app.Data_Integrity_Rate = uitable(app.interpolation_app);
            app.Data_Integrity_Rate.ColumnName = {''; ''; ''; ''};
            app.Data_Integrity_Rate.RowName = {};
            % Show the figure after all components are created
            app.interpolation_app.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = data_interpolation(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.interpolation_app)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.interpolation_app)
        end
    end
end