classdef data_revision < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MCP_app                matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        Import_Data            matlab.ui.container.Tab
        Import_Target_Data     matlab.ui.control.Button
        Import_Reference_Data  matlab.ui.control.Button
        Target_Panel           matlab.ui.container.Panel
        target_start_time      matlab.ui.control.Label
        target_end_time        matlab.ui.control.Label
        target_duration        matlab.ui.control.Label
        target_data_point      matlab.ui.control.Label
        target_mean_ws         matlab.ui.control.Label
        target_mean_wd         matlab.ui.control.Label
        target_file_name       matlab.ui.control.Label
        Reference_Panel        matlab.ui.container.Panel
        reference_start_time   matlab.ui.control.Label
        reference_end_time     matlab.ui.control.Label
        reference_duration     matlab.ui.control.Label
        reference_data_point   matlab.ui.control.Label
        reference_mean_ws      matlab.ui.control.Label
        reference_mean_wd      matlab.ui.control.Label
        reference_file_name    matlab.ui.control.Label
        Panel                  matlab.ui.container.Panel
        Demo_Table             matlab.ui.control.Table
        Reference_WS_Axes      matlab.ui.control.UIAxes
        Compare_Data           matlab.ui.container.Tab
        Compare_Panel          matlab.ui.container.Panel
        Polar_Axes             matlab.ui.container.Panel
        Sub_Axes_1             matlab.ui.control.UIAxes
        Sub_Axes_3             matlab.ui.control.UIAxes
        Sub_Axes_2             matlab.ui.control.UIAxes
        Caculate_Data          matlab.ui.container.Tab
        R2_Panel               matlab.ui.container.Panel
        R2_Table               matlab.ui.control.Table
        R2_Axes                matlab.ui.control.UIAxes
        Label                  matlab.ui.control.Label
        WD_Select              matlab.ui.control.DropDown
        Caculate_Yearly_Data   matlab.ui.control.Button
        Caculate_Offset        matlab.ui.control.Button
        Offset_Lable           matlab.ui.control.Label
        Export_Data            matlab.ui.container.Tab
        Start_Time_Hour        matlab.ui.control.Spinner
        End_Time_Hour          matlab.ui.control.Spinner
        Time_Select_Lable      matlab.ui.control.Label
        Label_2                matlab.ui.control.Label
        Start_Time             matlab.ui.control.DatePicker
        Label_3                matlab.ui.control.Label
        End_Time               matlab.ui.control.DatePicker
        Export                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp   % 主程序
        Target_Time
        Target_WS
        Target_WD
        Reference_Time
        Reference_WS
        Reference_WD
        ax      %极坐标
        export_app      %数据导出程序
        row             %导出数据行数
    end
    
    methods (Access = private)
        
        function [file,data] = Import_File(app)
            %导入数据
            [file,path] = uigetfile('*.txt');
            file_name = fullfile(path,file);
            h = waitbar(0.5,"数据导入中...");
            data = readtable(file_name,'Delimiter','tab','ReadRowNames',0,'ReadVariableNames',1);
            delete(h);
        end
        
        function Target_UI_Setup(app)
            target_time = app.Target_Time;
            data_size = size(app.Target_WS);
            app.target_start_time.Text = strcat("开始时间：",string(target_time(1)));
            app.target_end_time.Text = strcat("结束时间：",string(target_time(end)));
            app.target_duration.Text = strcat("测风时间：",string(between(target_time(1),target_time(end))));
            app.target_data_point.Text = strcat("数据点数：",string(data_size(1)));
            app.target_mean_ws.Text = strcat("平均风速：",num2str(mean(table2array(app.Target_WS))),"m/s");
            app.target_mean_wd.Text = strcat("平均风向：",num2str(mean(table2array(app.Target_WD))),"°");
        end
        
        function Reference_UI_Setup(app)
            reference_time = app.Reference_Time;
            data_size = size(app.Reference_WS);
            app.reference_start_time.Text = strcat("开始时间：",string(reference_time(1)));
            app.reference_end_time.Text = strcat("结束时间：",string(reference_time(end)));
            app.reference_duration.Text = strcat("测风时间：",string(between(reference_time(1),reference_time(end))));
            app.reference_data_point.Text = strcat("数据点数：",string(data_size(1)));
            app.reference_mean_ws.Text = strcat("平均风速：",num2str(mean(table2array(app.Reference_WS))),"m/s");
            app.reference_mean_wd.Text = strcat("平均风向：",num2str(mean(table2array(app.Reference_WD))),"°");
        end
        function ws_data = Caculate_Hour_Data(app,logical)
            %计算每小时风速
            %通过logical参数判断计算哪个数据集的参数
            ws_data = zeros(1,24);
            if logical == 1
                time = app.Target_Time;
                ws = app.Target_WS;
            end
            if logical == 0
                time = app.Reference_Time;
                ws = app.Reference_WS;
            end
            hour = time.Hour;
            ws = table2array(ws);
            for index = 0:23
                row = (hour == index);
                ws_data(index + 1) = mean(ws(row,:));
            end
        end
        
        function month_data = Caculate_Month_Data(app,logical)
            %计算月平均风速
            %通过logical参数判断计算哪个数据集的参数
            month_data = zeros(1,12);
            if logical == 1
                time = app.Target_Time;
                ws = app.Target_WS;
            end
            if logical == 0
                time = app.Reference_Time;
                ws = app.Reference_WS;
            end
            month = time.Month;
            ws = table2array(ws);
            for index = 1:12
                row = (month == index);
                month_data(index) = mean(ws(row,:));
            end
        end
        
        function fre_data = Caculate_Fre_Data(app,logical)
            %计算风速频率分布情况
            %通过logical参数判断计算哪个数据集的参数
            if logical == 1
                ws = app.Target_WS;
            end
            if logical == 0
                ws = app.Reference_WS;
            end
            ws = table2array(ws);
            hi = histogram(ws,'BinEdges',0:0.5:max(ws),'Normalization','probability');
            fre_data = hi.Values;
            close;
        end
        
        function Drow_Hour_WS(app)
            target_ws = Caculate_Hour_Data(app,1);
            reference_ws = Caculate_Hour_Data(app,0);
            time = 0:23;
            app.Sub_Axes_1.XLim = [-1,24];
            app.Sub_Axes_1.YLim = [0,max(target_ws) + 2];
            plot(app.Sub_Axes_1,time,target_ws,time,reference_ws);
            legend(app.Sub_Axes_1,["测风数据","参考数据"]);
        end
        
        function Drow_Month_WS(app)
            target_ws = Caculate_Month_Data(app,1);
            reference_ws = Caculate_Month_Data(app,0);
            time = 1:12;
            app.Sub_Axes_2.XLim = [0,13];
            app.Sub_Axes_2.YLim = [0,max(target_ws) + 2];
            plot(app.Sub_Axes_2,time,target_ws,time,reference_ws);
            xlable = strcat(string(1:12),"月");
            app.Sub_Axes_2.XTick = 1:12;
            app.Sub_Axes_2.XTickMode = 'manual';
            app.Sub_Axes_2.XTickLabel = xlable;
            legend(app.Sub_Axes_2,["测风数据","参考数据"]);
        end
        
        function Drow_Fre_WS(app)
            target_fre = Caculate_Fre_Data(app,1);
            reference_fre = Caculate_Fre_Data(app,0);
            x1 = 0:0.5:max(table2array(app.Target_WS)) - 0.5 ;
            x2 = 0:0.5:max(table2array(app.Reference_WS)) - 0.5;
            plot(app.Sub_Axes_3,x1,target_fre,x2,reference_fre);
            legend(app.Sub_Axes_3,["测风数据","参考数据"]);
        end
        
        function Drow_Wind_Rose(app,wd_data)
            %绘制风向玫瑰图
            RTickLable = {'N','NNE','NE','ENE','E','ESE','SE','SSE','S','SSW','SW','WSW','W','WWN','WN','WNW'};
            theta = deg2rad(0:22.5:360);
            wd = table2array(wd_data);
            po = polarhistogram(deg2rad(wd),'BinEdges',deg2rad(-11.25:22.5:348.75));
            fre = po.Values;
            close;      %关闭图窗
            fre(17) = fre(1);
            fre = fre./sum(fre(1:16));
            polarplot(theta,fre,'parent',app.ax);
            app.ax.ThetaZeroLocation = 'top';
            app.ax.ThetaDir = 'clockwise';
            app.ax.ThetaTick = 0:22.5:360;
            app.ax.ThetaTickLabel = RTickLable;
            hold(app.ax,'on');      %保留绘图区
        end
        function Drow_Compare_Data(app)
            %绘制数据对比图
            Drow_Hour_WS(app);
            Drow_Month_WS(app);
            Drow_Fre_WS(app);
            delete(app.ax);
            app.ax = polaraxes(app.Polar_Axes);
            Drow_Wind_Rose(app,app.Target_WD);
            Drow_Wind_Rose(app,app.Reference_WD);
        end
        
        function Drow_Demo_Table(app)
            %绘制示例表格
            time = string(datetime(2020,01,01,00:04,00,00)');
            ws = round(rand(5,1)*30,2);
            wd = round(rand(5,1)*360);
            data = [time,ws,wd];
            app.Demo_Table.Data = data;
        end
        
        function [x_data,y_data] = Caculate_Correlation(app,sector_index)
            %获取相关曲线中的x与y
            %具体方法见GBT 18710-2002
            x_data = [];        %存储参考数据
            y_data = [];        %存储测风数据
            target_time = app.Target_Time;
            reference_time = app.Reference_Time;
            target_ws = table2array(app.Target_WS);
            [~,~,ib] = intersect(target_time,reference_time);      %求交集
            reference_wd = table2array(app.Reference_WD(ib,:));      %长期测风数据对应年份风向数据
            reference_ws = table2array(app.Reference_WS(ib,:));      %同期风速
            if sector_index == 1
                %如果在第一个扇区内，则风向区间为 348.75-11.55
                reference_row = find(reference_wd> 348.75 | reference_wd < 11.25);      %省略find提高运算
                y_data = target_ws(reference_row);
                x_data = reference_ws(reference_row);
            end
            for index = 2:16
                if sector_index == index
                    reference_row = (reference_wd > 22.5*(index - 2) + 11.25 & reference_wd < 22.5*(index - 1) + 11.25);
                    x_data = reference_ws(reference_row);
                    y_data = target_ws(reference_row);
                end
            end
        end
        
        function Caculate_Diff(app)
            %计算代表年订正中的差值,拟合线的斜率、截距
            tic
            h = waitbar(0.5,"计算相关系数中...");
            slope = zeros(1,16);        %斜率
            intercept = zeros(1,16);        %截距
            for index = 1:16
                [x_data,y_data] = Caculate_Correlation(app,index);
                hi = polyfit(x_data,y_data,1);
                slope(index) = hi(1);
                intercept(index) = hi(2);
                r2 = corrcoef(x_data,y_data);
                R2(index) = r2(1,2);        %相关系数的平方
            end
            [~,ia,ib] = intersect(app.Target_Time,app.Reference_Time);
            target_ws = table2array(app.Target_WS(ia,:));
            reference_ws = table2array(app.Reference_WS(ib,:));
            r2 = corrcoef(target_ws,reference_ws);
            R2(end + 1) = r2(1,2);
            %             ax = uiaxes();
            %             scatter(ax,reference_ws,target_ws,'.')
            %计算差值
            target_time = app.Target_Time;
            reference_time = app.Reference_Time;
            [~,~,ib] = intersect(target_time,reference_time);
            x1 = mean(app.Reference_WS{:,:});       %多年平均值
            x2 = mean(app.Reference_WS{ib,:});      %同期年平均
            diff = (x1-x2).*slope;
            slope(end + 1) = NaN;
            intercept(end + 1) = NaN;
            diff(end + 1) = NaN;
            data = [slope',intercept',diff',R2'];
            app.R2_Table.Data = data;
            app.R2_Table.ColumnName = ["斜率","截距(m/s)","差值","相关系数"];
            app.R2_Table.RowName = {'N','NNE','NE','ENE','E','ESE','SE','SSE','S','SSW','SW','WSW','W','WWN','WN','WNW',"合计"};
            s = uistyle('HorizontalAlignment','left');
            addStyle(app.R2_Table,s);
            delete(h);
            toc
        end
        
        function Caculate_Year_WS(app)
            %计算长期参考数据的年平均风速
            cla(app.Reference_WS_Axes);
            time = app.Reference_Time;
            reference_ws_data = app.Reference_WS{:,:};
            year = time.Year;
            start_year = year(1);
            end_year = year(end);
            year_ws = [];       %存储长期数据年平均风速
            for year_index = start_year:end_year
                this_row = year == year_index;
                year_ws(end + 1) = mean(reference_ws_data(this_row));
            end
            x_lable = strcat(string(start_year:end_year),"年");
            histogram(app.Reference_WS_Axes,'Categories',x_lable,"BinCounts",year_ws);
            hold(app.Reference_WS_Axes,'on');
            plot(app.Reference_WS_Axes,mean(reference_ws_data));
   
        end
        
        function Export_UI_Setup(app)
            %初始化导出页面
            %为checkbox赋予标签，以选择要导出的数据
            %初始化时间选择器
            time = app.Target_Time(:,1);
            app.Start_Time.Value = time(1);
            app.End_Time.Value = time(end);
            hour = time.Hour;
            app.Start_Time_Hour.Value = hour(1);
            app.End_Time_Hour.Value = hour(end);
            %设置时间限制
            app.Start_Time.Limits = [time(1),time(end)];
            app.End_Time.Limits = [time(1),time(end)];
        end
        
        function Export_File(app)
            data = table(app.Target_Time,table2array(app.Target_WS),table2array(app.Target_WD));
            data = data(app.row,:);
            [file,path] = uiputfile('*.txt');
            file_name = fullfile(path,file);
            %整合数据
            varnames = ["time","Speed","Direction"];
            data.Properties.VariableNames = varnames;
            writetable(data,file_name,'Delimiter','tab');
            msgbox("数据导出完成！");
        end
        
        function Select_Data(app)
            start_year = app.Start_Time.Value.Year;
            start_month = app.Start_Time.Value.Month;
            start_day = app.Start_Time.Value.Day;
            start_hour = app.Start_Time_Hour.Value;
            end_year = app.End_Time.Value.Year;
            end_month = app.End_Time.Value.Month;
            end_day = app.End_Time.Value.Day;
            end_hour = app.End_Time_Hour.Value;
            start_time = datetime(start_year,start_month,start_day,start_hour,0,0);
            end_time = datetime(end_year,end_month,end_day,end_hour,0,0);
            start_row = find(app.Target_Time == start_time);
            end_row = find(app.Target_Time == end_time);
            if isempty(start_row) || isempty(end_row)
                errordlg("所选时间超出时间序列");
            end
            app.row = start_row:end_row;
        end
        
        function Drow_Correlation_Curve(app,wd_sector)
            cla(app.R2_Axes);       %清空坐标区
            %绘制相关曲线
            %读取所选择扇区4
            h = waitbar(0.5,"绘制相关曲线中...");
            sector_index = 0;
            for index = 1:16
                if isequal(app.WD_Select.Items{index},wd_sector)
                    sector_index = index;
                end
            end
            [x_data,y_data] = Caculate_Correlation(app,sector_index);
            p = polyfit(x_data,y_data,1);
            scatter(app.R2_Axes,x_data,y_data,'.');
            hold(app.R2_Axes,'on');
            f1 =  polyval(p,x_data);
            plot(app.R2_Axes,x_data,f1);
            legend(app.R2_Axes,["测风数据 vs. 参考数据","最佳拟合曲线"]);
            app.R2_Axes.XLim = [0,max(x_data) + 5];
            app.R2_Axes.YLim = [0,max(y_data) + 5];
            delete(h);
        end
        
        function Caculate_Data_Offset(app)
            %计算时间偏移量，使得相关系数取得最大值
            target_time = app.Target_Time;
            target_ws = app.Target_WS;
            reference_time = app.Reference_Time;
            reference_ws = app.Reference_WS;
            %创建时间差
            t1 = datetime(0,0,0,0,0,0);
            t2 = datetime(0,0,0,1,0,0);
            diff_time = t2-t1;      %一小时的时间差
            R2 = 0;
            for index = 1:100
                offset_time = reference_time;
                offset_time = offset_time + diff_time*(index - 50);
                [~,ia,ib] = intersect(target_time,offset_time);
                x = table2array(reference_ws);
                y = table2array(target_ws);
                r2 = corrcoef(x(ib),y(ia));
                R2(index) = r2(1,2);
            end
            [~,hour_index] = max(R2);
            offset_hour = hour_index - 50;
            app.Offset_Lable.Text = strcat(string(offset_hour),"小时");
            app.Reference_Time = app.Reference_Time + diff_time.*offset_hour;
            Caculate_Diff(app);     %更新表格
            Drow_Correlation_Curve(app,app.WD_Select.Value);        %更新相关曲线
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
            Drow_Demo_Table(app);
            app.Reference_WS_Axes.Visible = 'off';
        end

        % Button pushed function: Import_Target_Data
        function Import_Target_DataButtonPushed(app, event)
            [file,data] = Import_File(app);
            app.target_file_name.Text = strcat("文件名称：",string(file));
            app.Target_Time = data{:,1};
            app.Target_WS = data(:,2);
            app.Target_WD = data(:,3);
            Target_UI_Setup(app);
            
        end

        % Button pushed function: Import_Reference_Data
        function Import_Reference_DataButtonPushed(app, event)
            [file,data] = Import_File(app);
            app.reference_file_name.Text = strcat("文件名称：",string(file));
            app.Reference_Time = data{:,1};
            app.Reference_WS = data(:,2);
            app.Reference_WD = data(:,3);
            Reference_UI_Setup(app);
            app.Reference_WS_Axes.Visible = 'on';
            Caculate_Year_WS(app);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            switch selectedTab.Title
                case "导入数据"

                case "数据对比"
                    try
                        Drow_Compare_Data(app);     %绘制四个对比图，分别是日变化曲线，月变化曲线，风速分布情况，风向分布情况
                    catch
                        errordlg("数据缺失");
                    end
                case "相关性计算"
                    app.WD_Select.Value = {'N'};
                    Caculate_Diff(app);
                    Drow_Correlation_Curve(app,app.WD_Select.Value);
                case "导出数据"
                    Export_UI_Setup(app);
                    Select_Data(app);
            end
        end

        % Value changed function: WD_Select
        function WD_SelectValueChanged(app, event)
            value = app.WD_Select.Value;
            Drow_Correlation_Curve(app,value);
            
        end

        % Callback function
        function Sector_StateValueChanged(app, event)
            value = app.Sector_State.Value;
            if value == 1
                app.WD_Select.Enable = 'on';
            elseif value == 0
                app.WD_Select.Enable = 'off';
            end
            
        end

        % Button pushed function: Caculate_Yearly_Data
        function Caculate_Yearly_DataButtonPushed(app, event)
            %将风数据订正到代表年
            diff_data = app.R2_Table.Data(:,3);
            target_ws = table2array(app.Target_WS);
            target_wd = table2array(app.Target_WD);
            
            row = find(target_wd > 348.75 | target_wd < 11.25);
            target_ws(row) = target_ws(row) + diff_data(1);
            for sector = 2:16
                row = find(target_wd > 22.5*(sector - 2) + 11.25 & 22.5*(sector - 1) + 11.25);
                target_ws(row) = target_ws(row) + diff_data(sector);
            end
            app.Target_WS = array2table(target_ws);
            msgbox("数据订正完成");
        end

        % Callback function
        function Select_AllValueChanged(app, event)
            value = app.Select_All.Value;
            if value == 1
                app.export_ws.Value = 1;
                app.export_wd_Value = 1;
            elseif value == 0
                app.export_ws.Value = 0;
                app.export_wd_Value = 0;
            end
        end

        % Button pushed function: Export
        function ExportButtonPushed(app, event)
            Export_File(app);
        end

        % Button pushed function: Caculate_Offset
        function Caculate_OffsetButtonPushed(app, event)
            Caculate_Data_Offset(app);      %计算时间偏移量，以达到最大相关系数
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MCP_app = uifigure('Visible', 'off');
            app.MCP_app.Name = '代表年订正';
            app.TabGroup = uitabgroup(app.MCP_app);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.Import_Data = uitab(app.TabGroup);
            app.Import_Data.Title = '导入数据';
            app.Import_Target_Data = uibutton(app.Import_Data, 'push');
            app.Import_Target_Data.ButtonPushedFcn = createCallbackFcn(app, @Import_Target_DataButtonPushed, true);
            app.Import_Target_Data.Text = '导入测风数据';
            app.Import_Reference_Data = uibutton(app.Import_Data, 'push');
            app.Import_Reference_Data.ButtonPushedFcn = createCallbackFcn(app, @Import_Reference_DataButtonPushed, true);
            app.Import_Reference_Data.Text = '导入参考数据';
            app.Target_Panel = uipanel(app.Import_Data);
            app.Target_Panel.Title = '测风数据基本参数';
            app.target_start_time = uilabel(app.Target_Panel);
            app.target_start_time.Text = '开始时间：';
            app.target_end_time = uilabel(app.Target_Panel);
            app.target_end_time.Text = '结束时间：';
            app.target_duration = uilabel(app.Target_Panel);
            app.target_duration.Text = '测风时间：';
            app.target_data_point = uilabel(app.Target_Panel);
            app.target_data_point.Text = '数据点数：';
            app.target_mean_ws = uilabel(app.Target_Panel);
            app.target_mean_ws.Text = '平均风速：';
            app.target_mean_wd = uilabel(app.Target_Panel);
            app.target_mean_wd.Text = '平均风向：';
            app.target_file_name = uilabel(app.Target_Panel);
            app.target_file_name.Text = '文件名称：';
            app.Reference_Panel = uipanel(app.Import_Data);
            app.Reference_Panel.Title = '参考数据基本参数';
            app.reference_start_time = uilabel(app.Reference_Panel);
            app.reference_start_time.Text = '开始时间：';
            app.reference_end_time = uilabel(app.Reference_Panel);
            app.reference_end_time.Text = '结束时间：';
            app.reference_duration = uilabel(app.Reference_Panel);
            app.reference_duration.Text = '测风时间：';
            app.reference_data_point = uilabel(app.Reference_Panel);
            app.reference_data_point.Text = '数据点数：';
            app.reference_mean_ws = uilabel(app.Reference_Panel);
            app.reference_mean_ws.Text = '平均风速：';
            app.reference_mean_wd = uilabel(app.Reference_Panel);
            app.reference_mean_wd.Text = '平均风向：';
            app.reference_file_name = uilabel(app.Reference_Panel);
            app.reference_file_name.Text = '文件名称：';
            app.Panel = uipanel(app.Import_Data);
            app.Panel.Title = '导入数据格式';
            app.Demo_Table = uitable(app.Import_Data);
            app.Demo_Table.ColumnName = {'时间'; '风速'; '风向'};
            app.Demo_Table.RowName = {};
            app.Reference_WS_Axes = uiaxes(app.Import_Data);
            title(app.Reference_WS_Axes, '参考数据年平均风速')
            xlabel(app.Reference_WS_Axes, '年份')
            ylabel(app.Reference_WS_Axes, '风速(m/s)')
            app.Compare_Data = uitab(app.TabGroup);
            app.Compare_Data.Title = '数据对比';
            app.Compare_Panel = uipanel(app.Compare_Data);
            app.Polar_Axes = uipanel(app.Compare_Panel);
            app.Sub_Axes_1 = uiaxes(app.Compare_Panel);
            title(app.Sub_Axes_1, '日变化曲线')
            xlabel(app.Sub_Axes_1, '时间')
            ylabel(app.Sub_Axes_1, '风速(m/s)')
            app.Sub_Axes_3 = uiaxes(app.Compare_Panel);
            title(app.Sub_Axes_3, '风速分布情况')
            xlabel(app.Sub_Axes_3, '风速(m/s)')
            ylabel(app.Sub_Axes_3, '频率(%)')
            app.Sub_Axes_2 = uiaxes(app.Compare_Panel);
            title(app.Sub_Axes_2, '月变化曲线')
            xlabel(app.Sub_Axes_2, '月份')
            ylabel(app.Sub_Axes_2, '风速(m/s)')
            app.Caculate_Data = uitab(app.TabGroup);
            app.Caculate_Data.Title = '相关性计算';
            app.R2_Panel = uipanel(app.Caculate_Data);
            app.R2_Table = uitable(app.R2_Panel);
            app.R2_Table.ColumnName = {''; ''; ''; ''};
            app.R2_Table.RowName = {};
            app.R2_Axes = uiaxes(app.Caculate_Data);
            title(app.R2_Axes, '相关曲线')
            xlabel(app.R2_Axes, '参考风速(m/s)')
            ylabel(app.R2_Axes, '测风风速(m/s)')
            app.Label = uilabel(app.Caculate_Data);
            app.Label.Text = '风向';
            app.WD_Select = uidropdown(app.Caculate_Data);
            app.WD_Select.Items = {'N', 'NNE', 'NE', 'ENE', 'E', 'ESE', 'SE', 'SSE', 'S', 'SSW', 'SW', 'WSW', 'W', 'WWN', 'WN', 'WNW'};
            app.WD_Select.ValueChangedFcn = createCallbackFcn(app, @WD_SelectValueChanged, true);
            app.WD_Select.Value = 'N';
            app.Caculate_Yearly_Data = uibutton(app.Caculate_Data, 'push');
            app.Caculate_Yearly_Data.ButtonPushedFcn = createCallbackFcn(app, @Caculate_Yearly_DataButtonPushed, true);
            app.Caculate_Yearly_Data.Text = '代表年订正';
            app.Caculate_Offset = uibutton(app.Caculate_Data, 'push');
            app.Caculate_Offset.ButtonPushedFcn = createCallbackFcn(app, @Caculate_OffsetButtonPushed, true);
            app.Caculate_Offset.Text = '计算时间偏移量';
            app.Offset_Lable = uilabel(app.Caculate_Data);
            app.Offset_Lable.Text = '';
            app.Export_Data = uitab(app.TabGroup);
            app.Export_Data.Title = '导出数据';
            app.Start_Time_Hour = uispinner(app.Export_Data);
            app.Start_Time_Hour.Limits = [0 23];
            app.End_Time_Hour = uispinner(app.Export_Data);
            app.End_Time_Hour.Limits = [0 23];
            app.Time_Select_Lable = uilabel(app.Export_Data);
            app.Time_Select_Lable.Text = '选择导出数据的时间区间：';
            app.Label_2 = uilabel(app.Export_Data);
            app.Label_2.Text = '开始时间';
            app.Start_Time = uidatepicker(app.Export_Data);
            app.Start_Time.DisplayFormat = 'uuuu/MM/dd';
            app.Label_3 = uilabel(app.Export_Data);
            app.Label_3.Text = '结束时间';
            app.End_Time = uidatepicker(app.Export_Data);
            app.End_Time.DisplayFormat = 'uuuu/MM/dd';
            app.Export = uibutton(app.Export_Data, 'push');
            app.Export.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.Export.Text = '执行';
            % Show the figure after all components are created
            app.MCP_app.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = data_revision(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MCP_app)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MCP_app)
        end
    end
end