classdef export_data < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        export_data_app    matlab.ui.Figure
        Panel              matlab.ui.container.Panel
        Select_All         matlab.ui.control.CheckBox
        Caculate           matlab.ui.control.Button
        Label              matlab.ui.control.Label
        Start_Time         matlab.ui.control.DatePicker
        Label_2            matlab.ui.control.Label
        End_Time           matlab.ui.control.DatePicker
        Start_Time_Hour    matlab.ui.control.Spinner
        End_Time_Hour      matlab.ui.control.Spinner
        Time_Select_Lable  matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp   % 主程序
        WS
        WD
        Temp
        BP
        WS_Height
        WD_Height
        Temp_Height
        BP_Height
        WS_size
        WD_size
        Temp_size
        BP_size
        check_box       %存储多选框
        data_size       %所有数据总列数，不包含时间列
        row         %导出数据行
        column      %导出数据列
        VarName
    end
    
    methods (Access = private)
        
        function Data_Setup(app)
            app.WS = app.CallingApp.WS_Hour;
            app.WD = app.CallingApp.WD_Hour;
            app.Temp = app.CallingApp.Temp_Hour;
            app.BP = app.CallingApp.BP_Hour;
            app.WS_size = size(app.WS);
            app.WD_size = size(app.WD);
            app.Temp_size = size(app.Temp);
            app.BP_size = size(app.BP);
            app.WS_Height = app.CallingApp.WS.Properties.CustomProperties.height;
            app.WD_Height = app.CallingApp.WD.Properties.CustomProperties.height;
            app.Temp_Height = app.CallingApp.Temp.Properties.CustomProperties.height;
            app.BP_Height = app.CallingApp.BP.Properties.CustomProperties.height;
            app.WS.Properties.VariableNames = strcat("Speed  ",string(app.WS_Height),"m");
            app.WD.Properties.VariableNames = strcat("Direction  ",string(app.WD_Height),"m");
            app.Temp.Properties.VariableNames = strcat("Temp  ",string(app.Temp_Height),"m");
            app.BP.Properties.VariableNames = strcat("BP  ",string(app.BP_Height),"m");
        end
        
        function UI_Setup(app)
            app.data_size = app.WS_size(2) - 1 + app.WD_size(2) - 1 +app.Temp_size(2) - 1 + app.BP_size(2) - 1;
            %为checkbox赋予标签，以选择要导出的数据
            for index = 2:app.WS_size(2)
                label(index - 1) = strcat("Speed ",string(app.WS_Height(index)),"m");
            end
            for index = 2:app.WD_size(2)
                label(app.WS_size(2) +index - 2) = strcat("Direction ",string(app.WD_Height(index)),"m");
            end
            for index = 2:app.Temp_size(2)
                label(app.WS_size(2) - 1 + app.WD_size(2) - 1 + index - 1)...
                    = strcat("Temp ",string(app.Temp_Height(index)),"m");
            end
            for index = 2:app.BP_size(2)
                label(app.WS_size(2) - 1 + app.WD_size(2) - 1 + app.Temp_size(2) - 1 + index - 1)...
                    = strcat("BP ",string(app.BP_Height(index)),"m");
            end
            app.VarName = label;        %将label作为导出数据的列名称
            for index = 1:app.data_size
                check_box_index = uicheckbox(app.Panel);
                check_box_index.Text = label(index);
                check_box_index.Position = [10,530 - (index - 1)*20,110,20];
                app.check_box{index} = check_box_index;
            end
            %初始化时间选择器
            time = app.WS{:,1};
            app.Start_Time.Value = time(1);
            app.End_Time.Value = time(end);
            hour = time.Hour;
            app.Start_Time_Hour.Value = hour(1);
            app.End_Time_Hour.Value = hour(end);
            %设置时间限制
            app.Start_Time.Limits = [time(1),time(end)];
            app.End_Time.Limits = [time(1),time(end)];
        end
        
        function Export_Data(app)
            %创建导出文件，并将数据写入文件中
            Select_Data(app);
            row_size = size(app.row);
            column_size = size(app.column);
            all_data = [app.WS(:,2:end),app.WD(:,2:end),app.Temp(:,2:end),app.BP(:,2:end)];
            [file,path] = uiputfile('*.txt');
            file_name = fullfile(path,file);
            %整合数据
            data(:,1) = app.WS(app.row,1);
            data(:,2:column_size(2) + 1) = all_data(app.row,app.column);
            varnames = ["time",app.VarName(app.column)];
            data.Properties.VariableNames = varnames;
            writetable(data,file_name,'Delimiter','tab');
        end
        
        function Select_Data(app)
            %选择要导出的数据列
            data_column = zeros(1,app.data_size);       %要导出的数据列为1，不导出的数据列为0
            for index = 1:app.data_size
                check_box_index = app.check_box{:,index};
                if check_box_index.Value == 1
                    data_column(index) = 1;
                elseif check_box_index.Value == 0
                    data_column(index) = 0;
                end
            end
            app.column = find(data_column == 1);
            %选择要导出的数据行
            time = app.WS{:,1};
            start_year = app.Start_Time.Value.Year;
            start_month = app.Start_Time.Value.Month;
            start_day = app.Start_Time.Value.Day;
            start_hour = app.Start_Time_Hour.Value;
            end_year = app.End_Time.Value.Year;
            end_month = app.End_Time.Value.Month;
            end_day = app.End_Time.Value.Day;
            end_hour = app.End_Time_Hour.Value;
            start_time = datetime(start_year,start_month,start_day,start_hour,0,0);
            end_time = datetime(end_year,end_month,end_day,end_hour,0,0);
            start_row = find(time == start_time);
            end_row = find(time == end_time);
            if isempty(start_row) || isempty(end_row)
                errordlg("所选时间超出时间序列");
            end
            app.row = start_row:end_row;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
            Data_Setup(app);
            UI_Setup(app);
        end

        % Value changed function: Select_All
        function Select_AllValueChanged(app, event)
            value = app.Select_All.Value;
            if value == 1
                %当按下全选复选框时，所有数据被选中
                for index = 1:app.data_size
                    check_box_index = app.check_box{:,index};
                    check_box_index.Value = 1;
                end
            end
            if value == 0
                for index = 1:app.data_size
                    check_box_index = app.check_box{:,index};
                    check_box_index.Value = 0;
                end
            end
        end

        % Button pushed function: Caculate
        function CaculatePushed(app, event)
            Export_Data(app);       %导出数据
            delete(app.export_data_app);
            msgbox("数据导出完成!");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.export_data_app = uifigure('Visible', 'off');
            app.export_data_app.Name = '导出数据';
            app.Panel = uipanel(app.export_data_app);
            app.Select_All = uicheckbox(app.export_data_app);
            app.Select_All.ValueChangedFcn = createCallbackFcn(app, @Select_AllValueChanged, true);
            app.Select_All.Text = '全选';
            app.Caculate = uibutton(app.export_data_app, 'push');
            app.Caculate.ButtonPushedFcn = createCallbackFcn(app, @CaculatePushed, true);
            app.Caculate.Text = '执行';
            app.Label = uilabel(app.export_data_app);
            app.Label.Text = '开始时间';
            app.Start_Time = uidatepicker(app.export_data_app);
            app.Start_Time.DisplayFormat = 'uuuu/MM/dd';
            app.Label_2 = uilabel(app.export_data_app);
            app.Label_2.Text = '结束时间';
            app.End_Time = uidatepicker(app.export_data_app);
            app.End_Time.DisplayFormat = 'uuuu/MM/dd';
            app.Start_Time_Hour = uispinner(app.export_data_app);
            app.Start_Time_Hour.Limits = [0 23];
            app.End_Time_Hour = uispinner(app.export_data_app);
            app.End_Time_Hour.Limits = [0 23];
            app.Time_Select_Lable = uilabel(app.export_data_app);
            app.Time_Select_Lable.Text = '选择导出数据的时间区间：';
            % Show the figure after all components are created
            app.export_data_app.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = export_data(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.export_data_app)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.export_data_app)
        end
    end
end