classdef import_data < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        import_data_app  matlab.ui.Figure
        Data_Set_Up      matlab.ui.container.Panel
        Data_Property    matlab.ui.container.Panel
        Label            matlab.ui.control.Label
        Data_Type        matlab.ui.control.DropDown
        Label_2          matlab.ui.control.Label
        Height           matlab.ui.control.EditField
        mLabel           matlab.ui.control.Label
        Finish           matlab.ui.control.Button
        Label_3          matlab.ui.control.Label
        Head_Line_Value  matlab.ui.control.NumericEditField
        Read_Data        matlab.ui.control.Button
        Channel_Panel    matlab.ui.container.Panel
    end

    
    properties (Access = private)
        CallingApp      % 主程序
        Data_Lable      %Lable框
        index   double        %数据属性索引
        temp_data   %临时变量
        file_name     %文件名
        data        %读入的数据
        time        %时间数据
        data_attr       %原始数据列名称
        data_type       %存储数据类型
        data_height     %存储数据高度
        sd_height       %存储风速标准差高度
        sss
        channel_name_label   %存储label组件
        channel_data_label
        %******************
        WS
        WD
        Temp
        BP
        SD
        WS_size
        WD_size
        Temp_size
        BP_size
        SD_size
        WS_index=1;      %风速数组索引
        WD_index=1;      %风向数组索引
        BP_index=1;      %气压数组索引
        Temp_index=1;    %温度数组索引
        SD_index = 1;       %标准差数组索引
        data_size       %数据大小
        channel_name = cell(0,0);
        channel_data = cell(0,0);

    end
    
    % 设立公共属性，将数据类型与数据高度传送到主函数
    properties (Access = public)

    end
    
    methods (Access = private)
        
        % Data_Lable的回调函数
        function Data_LableValueChanged(app, ~)
            value = app.Data_Lable.Value;
            try
                label_index = get_Channel_Index(app,value);
                Creat_Channel_Label(app,app.channel_name(:,label_index),app.channel_data(:,label_index));    %创建通道信息
            catch
            end
            indexc = strfind(app.data_attr,value);
            app.index = find(~cellfun('isempty',indexc));
            app.temp_data = app.data_type(app.index);
            % 存储数据类型，用于选中标签时显示
            if isequal(app.temp_data,{'WS'})
                app.Data_Type.Value = '风速';
            elseif isequal(app.temp_data,{'WD'})
                app.Data_Type.Value = '风向';
            elseif isequal(app.temp_data,{'Temp'})
                app.Data_Type.Value = '温度';
            elseif  isequal(app.temp_data,{'BP'})
                app.Data_Type.Value = '气压';
            elseif  isequal(app.temp_data,{'SD'})
                app.Data_Type.Value = '风速标准差';
            else
                app.Data_Type.Value = '选择数据类型';
            end
            % 存储数据高度，用于显示高度
            app.Height.Value = num2str((cell2mat(app.data_height(app.index))));
        end
        
        function get_File_Info(app)
            %获取文件的绝对路径及头文件行数信息
            [file,path] = uigetfile('*.txt');
            app.file_name = fullfile(path,file);
            if isequal(file,0) && isequal(path , 0)
                return;     %如果没有输入文件路径，终止回调
            end
            ops = detectImportOptions(app.file_name);   %基于文件内容预览数据
            app.Head_Line_Value.Value = ops.DataLines(1) - 2;  %将头文件行数显示到程序中
        end
        
        function Import_Data(app)
            %读取文件数据，创建table容器，并将时间信息存入table的第一列中
            head_line = app.Head_Line_Value.Value;      %从主程序读取头文件行数
            app.data = readtable(app.file_name,'Delimiter','tab','ReadRowNames',false,'ReadVariableNames',true,'HeaderLines',head_line);    %读取数据
            app.data_size = size(app.data);     %数据大小
            app.time = app.data{:,1};   %读取时间数据
            %创建table容器存储风速、风向、温度、气压、风速标准差数据
            app.WS = table();
            app.WD = table();
            app.Temp = table();
            app.BP = table();
            app.SD = table(); 
            %为数据添加高度属性,要访问数据高度属性，通过app.WS.Properties.CustomProperties.height访问
            app.WS = addprop(app.WS,'height','table');
            app.WD = addprop(app.WD,'height','table');
            app.Temp = addprop(app.Temp,'height','table');
            app.BP = addprop(app.BP,'height','table');
            app.SD = addprop(app.SD,'height',"table");         
            %将时间存入WS,WD,Temp,BP的第一列用作索引
            app.WS(:,1) = app.data(:,1);
            app.WD(:,1) = app.data(:,1);
            app.Temp(:,1) = app.data(:,1);
            app.BP(:,1) = app.data(:,1);
            app.SD(:,1) = app.data(:,1);
        end
        
        function Data_Setup(app)
            %初始化数据，将数据存入table的第二列后
            %通过遍历数据列，将aap.data中每一列的数据分别存入对应的table中
            for i = 2:app.data_size(2)
                if isequal(app.data_type(i),{'WS'})
                    app.WS_index = app.WS_index + 1;
                    app.WS(:,app.WS_index) = app.data(:,i);
                    app.WS.Properties.CustomProperties.height(app.WS_index) = cell2mat(app.data_height(i));
                elseif  isequal(app.data_type(i),{'WD'})
                    app.WD_index = app.WD_index + 1;
                    app.WD(:,app.WD_index) = app.data(:,i);
                    app.WD.Properties.CustomProperties.height(app.WD_index) = cell2mat(app.data_height(i));
                elseif  isequal(app.data_type(i),{'Temp'})
                    app.Temp_index = app.Temp_index + 1;
                    app.Temp(:,app.Temp_index) = app.data(:,i);
                    app.Temp.Properties.CustomProperties.height(app.Temp_index) = cell2mat(app.data_height(i));
                elseif  isequal(app.data_type(i),{'BP'})
                    app.BP_index = app.BP_index + 1;
                    app.BP(:,app.BP_index) = app.data(:,i);
                    app.BP.Properties.CustomProperties.height(app.BP_index) = cell2mat(app.data_height(i));
                elseif  isequal(app.data_type(i),{'SD'})
                    app.SD_index = app.SD_index + 1;
                    app.SD(:,app.SD_index) = app.data(:,i);
                    app.SD.Properties.CustomProperties.height(app.BP_index) = cell2mat(app.data_height(i));
                end
            end
            %因为table的第一列存储时间信息，所以这里将第一列的高度信息设置为0
            app.WS.Properties.CustomProperties.height(1) = 0;
            app.WD.Properties.CustomProperties.height(1) = 0;
            app.Temp.Properties.CustomProperties.height(1) = 0;
            app.BP.Properties.CustomProperties.height(1) = 0;
            app.SD.Properties.CustomProperties.height(1) = 0;
            %初始化table的变量名
            app.WS.Properties.VariableNames = strcat("Speed  ",string(app.WS.Properties.CustomProperties.height),"m");
            app.WD.Properties.VariableNames = strcat("Direction  ",string(app.WD.Properties.CustomProperties.height),"m");
            app.Temp.Properties.VariableNames = strcat("Temp  ",string(app.Temp.Properties.CustomProperties.height),"m");
            app.BP.Properties.VariableNames = strcat("BP  ",string(app.BP.Properties.CustomProperties.height),"m");
            %读取风速、风向、温蒂、气压、风速标准差的数据大小
            app.WS_size = size(app.WS);
            app.WD_size = size(app.WD);
            app.Temp_size = size(app.Temp);
            app.BP_size = size(app.BP);
            app.SD_size = size(app.SD);
        end
        
        function Transfor_Data(app)
            %将初始化后的数据传输到主程序
            app.CallingApp.time = app.time;
            app.CallingApp.WS = app.WS;
            app.CallingApp.WD = app.WD;
            app.CallingApp.Temp = app.Temp;
            app.CallingApp.BP = app.BP;
            app.CallingApp.SD = app.SD;
            app.CallingApp.WS_size = app.WS_size;
            app.CallingApp.WD_size = app.WD_size;
            app.CallingApp.Temp_size = app.Temp_size;
            app.CallingApp.BP_size = app.BP_size;
            app.CallingApp.SD_size = app.SD_size;
        end
        
        function UI_Setup(app)
            %初始化窗口，待数据导入后执行
            app.data_attr = app.data.Properties.VariableNames;
            %attr存储数据columnname
            % 初始化变量
            app.index = 2;
            % Create Data_Lable
            attr_size = size(app.data_attr);
            attr_data = cell(1,attr_size(2)-1);
            for i = 2:attr_size(2)
                attr_data(i-1) = app.data_attr(i);
            end
            % 创建元胞数组存储数据类型
            app.data_type = cell(1,attr_size(2));
            app.data_type(1) = {'time'};
            
            % 创建元胞数组存储数据高度
            app.data_height = cell(1,attr_size(2));
            app.data_height(1) = num2cell(-1);     %时间列没有高度属性
            % 创建列表框
            app.Data_Lable = uilistbox(app.Data_Set_Up, 'items',attr_data);
            app.Data_Lable.Position = [1 0 363 461];
            
            % 调用列表框回调函数
            app.Data_Lable.ValueChangedFcn = createCallbackFcn(app, @Data_LableValueChanged, true);
            return;
        end
        
%         function Data_Lable_Setup(app)
%             %为数据添加标签、从原始文件中读取
%             ops = delimitedTextImportOptions('DataLines',[1 app.Head_Line_Value.Value],'Delimiter','\t');
%             head_data = readtable(app.file_name,ops);       %读取头文件信息
%             row_name = head_data{:,1};
%             row_data = head_data{:,2};      %第一列存储名称、第二列存储数据
%             attr = app.data_attr;
%             attr = char(attr(2:end));       %第一个为时间标签
%             %提取attr中的数字数据，以判断通道信息
%             attr_index = [];    %存储序列位置
%             row_name = string(row_name)
%             row = strfind(row_name,'Channel');
%             row_size = size(row);
%             for index = 1:row_size(1)
%                 if isequal(row(index),{[1]})
%                     attr_index(end + 1) = index;
%                 end
%             end
%             attr_index_size = size(attr_index);
%             for index = 1:attr_index_size(2)
%                  des(index) = row_data(attr_index(index) + 2);
%             end
%             attr_size = size(attr);
%             for index = 1:attr_size(1)
%                 ss = attr(index,:);
%                 A = isstrprop(ss,'digit');
%                 B = ss(A);
%                 attr_index(index) = str2num(B);     %通道索引值
%                 if attr_index(index) == index
%                     app.data_attr(index) = strcat(app.data_attr(index),des(index));
%                 end
%             end
%             
%         end


        function Channel_Setup(app)
            %从测风数据中分离出通道信息
            ops = delimitedTextImportOptions('DataLines',[1 app.Head_Line_Value.Value],'EmptyLineRule','read','Delimiter','\t');
            head_data = readtable(app.file_name,ops);
            row_name =  head_data{:,1};
            row_data = head_data{:,2};
            row_size = size(head_data);
            for row_index = 1:row_size(1)
                row_name_index = row_name(row_index);
                row_data_index = row_data(row_index);
                if isequal(strfind(row_name_index,'Channel'),{1})     
                    row(row_index) = 1;     %找到储存通道信息的行
                else
                    row(row_index) = 0;
                end
            end
            channel_info = find(row == 1);
            channel_size = size(channel_info);
            for channel_index  = 1:channel_size(2) - 1
                app.channel_name(:,channel_index) = row_name(channel_info(channel_index):channel_info(channel_index + 1) - 1,:)';
                app.channel_data(:,channel_index) = row_data(channel_info(channel_index):channel_info(channel_index + 1) - 1,:)';
            end
            app.channel_name(:,channel_size(2)) = row_name(channel_info(end):end,:)';      %存储数据通道名称
            app.channel_data(:,channel_size(2)) = row_data(channel_info(end):end,:)';      %存储数据通道信息
        end
        
        function Creat_Channel_Label(app,name,data)
            %将通道信息标志在程序界面中，先尝试删除已有数据
            try
                delete(app.channel_name_label);     
                delete(app.channel_data_label);
            catch
            end
            name_size = size(name);
            for label_index = 1:name_size(1)
                name_label= uilabel(app.Channel_Panel);
                data_label = uilabel(app.Channel_Panel);
                name_label.Position = [14,170 - (label_index - 1) * 20,180,20];
                data_label.Position = [210,170 - (label_index - 1) * 20,130,20];
                name_label.Text = name(label_index);
                data_label.Text = data(label_index);
                app.channel_name_label(label_index) = name_label;
                app.channel_data_label(label_index) = data_label;
            end
        end
        
        function index = get_Channel_Index(~,channel_value)
            %获取通道索引值，从标签中获取
            channel_value = char(channel_value);
            A = isstrprop(channel_value,'digit');
            B = channel_value(A);
            index = str2double(B);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;        %绑定主程序
            get_File_Info(app);     %获取要导入数据的信息
        end

        % Value changed function: Data_Type
        function Data_TypeValueChanged(app, event)
            value = app.Data_Type.Value;
            % 将数据类型存入data_type元胞数组
            if isequal(value,'风速')
                app.data_type(app.index) = {'WS'};
            elseif isequal(value,'风向')
                app.data_type(app.index) = {'WD'};
            elseif isequal(value,'温度')
                app.data_type(app.index) = {'Temp'};
            elseif isequal(value,'气压')
                app.data_type(app.index) = {'BP'};
            elseif isequal(value,'风速标准差')
                app.data_type(app.index) = {'SD'};
            end
        end

        % Value changed function: Height
        function HeightValueChanged(app, event)
            value = app.Height.Value;
            if app.index == 0
                app.index = 2;
            end
            app.data_height(app.index) = num2cell(str2double((value)));
        end

        % Button pushed function: Finish
        function FinishPushed(app, event)
            % 完成数据初始化并将数据类型与数据高度传送到主程序，关闭子程序
            Data_Setup(app);
            Transfor_Data(app);
            delete(app.import_data_app);      % 关闭子程序
        end

        % Button pushed function: Read_Data
        function Read_DataButtonPushed(app, event)
           h = waitbar(0.5,"数据读取中...");
            Import_Data(app);
           %导入数据后初始化界面
           UI_Setup(app);
           Channel_Setup(app);      %初始化通道信息
           Creat_Channel_Label(app,app.channel_name(:,1),app.channel_data(:,1));
           delete(h);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.import_data_app = uifigure('Visible', 'off');
            app.import_data_app.Name = '数据初始化';
            app.Data_Set_Up = uipanel(app.import_data_app);
            app.Data_Set_Up.Title = '数据标签';
            app.Data_Property = uipanel(app.import_data_app);
            app.Data_Property.Title = '数据属性';
            app.Label = uilabel(app.Data_Property);
            app.Label.Text = '数据类型';
            app.Data_Type = uidropdown(app.Data_Property);
            app.Data_Type.Items = {'选择数据类型', '风速', '风向', '温度', '气压', '风速标准差'};
            app.Data_Type.ValueChangedFcn = createCallbackFcn(app, @Data_TypeValueChanged, true);
            app.Data_Type.Value = '选择数据类型';
            app.Label_2 = uilabel(app.Data_Property);
            app.Label_2.Text = '数据高度';
            app.Height = uieditfield(app.Data_Property, 'text');
            app.Height.ValueChangedFcn = createCallbackFcn(app, @HeightValueChanged, true);
            app.mLabel = uilabel(app.Data_Property);
            app.mLabel.Text = 'm';
            app.Finish = uibutton(app.import_data_app, 'push');
            app.Finish.ButtonPushedFcn = createCallbackFcn(app, @FinishPushed, true);
            app.Finish.Text = '完成';
            app.Label_3 = uilabel(app.import_data_app);
            app.Label_3.Text = '头文件行数：';
            app.Head_Line_Value = uieditfield(app.import_data_app, 'numeric');
            app.Read_Data = uibutton(app.import_data_app, 'push');
            app.Read_Data.ButtonPushedFcn = createCallbackFcn(app, @Read_DataButtonPushed, true);
            app.Read_Data.Text = '读取数据';
            app.Channel_Panel = uipanel(app.import_data_app);
            app.Channel_Panel.Title = '通道信息';
            % Show the figure after all components are created
            app.import_data_app.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = import_data(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.import_data_app)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.import_data_app)
        end
    end
end