classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mainapp                matlab.ui.Figure
        File                   matlab.ui.container.Menu
        File_Open              matlab.ui.container.Menu
        File_Export            matlab.ui.container.Menu
        Data_Verification      matlab.ui.container.Menu
        Rationality            matlab.ui.container.Menu
        Correlation            matlab.ui.container.Menu
        Trendiness             matlab.ui.container.Menu
        Data_Interpolation     matlab.ui.container.Menu
        Data_Correction        matlab.ui.container.Menu
        MCP                    matlab.ui.container.Menu
        Analyse                matlab.ui.container.Menu
        Wind_Shear             matlab.ui.container.Menu
        Turbulence             matlab.ui.container.Menu
        TabGroup               matlab.ui.container.TabGroup
        Summary                matlab.ui.container.Tab
        Sub_Summary            matlab.ui.container.Panel
        Start_Time             matlab.ui.control.Label
        Start_Time_Data        matlab.ui.control.Label
        End_Time               matlab.ui.control.Label
        End_Time_Data          matlab.ui.control.Label
        Mean_Temp              matlab.ui.control.Label
        Mean_Temp_Data         matlab.ui.control.Label
        Mean_BP                matlab.ui.control.Label
        Mean_BP_Data           matlab.ui.control.Label
        Mean_WS_Highest        matlab.ui.control.Label
        Mean_WS_Highest_Data   matlab.ui.control.Label
        Mean_WPD_50            matlab.ui.control.Label
        Mean_WPD_50_Data       matlab.ui.control.Label
        Wind_Power_Class       matlab.ui.control.Label
        Wind_Power_Class_Data  matlab.ui.control.Label
        Power_Law              matlab.ui.control.Label
        Power_Law_Data         matlab.ui.control.Label
        Mean_rho               matlab.ui.control.Label
        Mean_rho_data          matlab.ui.control.Label
        Mon_WS_UIAxes          matlab.ui.control.UIAxes
        Wind_Rose              matlab.ui.container.Tab
        Label                  matlab.ui.control.Label
        Data_Type              matlab.ui.control.DropDown
        Label_2                matlab.ui.control.Label
        WS_Height              matlab.ui.control.DropDown
        Label_3                matlab.ui.control.Label
        WD_Height              matlab.ui.control.DropDown
        Time_Type              matlab.ui.container.ButtonGroup
        Year_Data              matlab.ui.control.RadioButton
        Month_Data             matlab.ui.control.RadioButton
        Rose                   matlab.ui.container.Panel
        UITable                matlab.ui.control.Table
        Table_Title            matlab.ui.control.Label
        Rose_Table             matlab.ui.control.StateButton
        Diurnal_Profile        matlab.ui.container.Tab
        Dirunal_Profile_Panel  matlab.ui.container.Panel
        Time_Switch            matlab.ui.container.ButtonGroup
        Year_WS_Diurnal        matlab.ui.control.RadioButton
        Year_WPD_Diurnal       matlab.ui.control.RadioButton
        Mon_WS_Diurnal         matlab.ui.control.RadioButton
        Mon_WPD_Diurnal        matlab.ui.control.RadioButton
        Label_5                matlab.ui.control.Label
        Diurnal_WS_Height      matlab.ui.control.DropDown
        Histogram              matlab.ui.container.Tab
        Histogram_Panel        matlab.ui.container.Panel
        Histogram_Table        matlab.ui.control.Table
        Label_4                matlab.ui.control.Label
        Histogram_WS_Height    matlab.ui.control.DropDown
        Table_Switch           matlab.ui.control.StateButton
        Weibull_Table          matlab.ui.control.Table
    end

    properties (Access = private)      %创建私有属性
        import_data_app    %导入数据、初始化程序
        rationality_app     %合理性验证程序
        correlation_app     %相关性验证程序
        trendiness_app      %趋势性验证程序
        interpolation_app      %数据插补程序
        wind_shear_app      %风廓线分析程序
        turbulence_app      %湍流分析程序
        export_data_app     %数据导出程序
        mcp_app             %代表年订正程序
        ax      %极坐标轴对象
        sub_axes      %subplot极坐标句柄
        Diurnal_Profile_Axes
        Histogram_Axes
        RTickLable = {'N','NNE','NE','ENE','E','ESE','SE','SSE','S','SSW','SW','WSW','W','WWN','WN','WNW'};
        Mon_WS %存储月平均数据
        ti        % 存储月变化3*4布局
        hist_data       %风速分布情况
        
    end
    
    properties (Access = public)       %创建公有属性,以便子程序访问
        %**********************************%
        time
        WS      %风速
        WD      %风向
        Temp    %温度
        BP      %气压
        SD      %标准差
        WS_size     %风速数据大小
        WD_size     %风向数据大小;
        Temp_size
        BP_size
        SD_size
        rho     %保存空气密度
        %********************************%
        data_type       %存储数据类型
        data_height     %存储数据高度
        %****************小时平均数据*******************%
        WS_Hour
        WD_Hour
        Temp_Hour
        BP_Hour
        SD_Hour
        %************************************************%
        
    end
    
    %***********自建组件属性定义****************%

    %*****************************************%
    
    
    %****************************自建函数*******************%
    
    
    methods (Access = private)
        
        function Creat_Axes(app)        %创建极坐标轴
            app.ax = polaraxes('parent',app.Rose);
            app.ax.RAxis.Visible = 'off';
        end
        
        %************************读取风数据文件*****************************%
        function Data_Setup(app)
            %数据初始化
      
        end
        %************************绘制多高度风向玫瑰图************************%
        function Drow_All_WD_Rose(app,wd_data)      
            %绘制多高度风向玫瑰图
            theta = deg2rad(0:22.5:360);
            wd = wd_data;
            po = polarhistogram(deg2rad(wd),'BinEdges',deg2rad(-11.25:22.5:348.75));
            fre = po.Values;
            close;      %关闭图窗
            fre(17) = fre(1);
            fre = fre./sum(fre(1:16));
            polarplot(theta,fre,'parent',app.ax);
            app.ax.ThetaZeroLocation = 'top';
            app.ax.ThetaDir = 'clockwise';
            app.ax.ThetaTick = 0:22.5:360;
            app.ax.ThetaTickLabel = app.RTickLable;
            hold(app.ax,'on');      %保留绘图区
        end
        %***********************绘制单一风向玫瑰图*************************%
        
        function Drow_WD_Rose(app,wd_data)      
            %绘制单一高度风向玫瑰图
            po = polarhistogram(deg2rad(wd_data),'BinEdges',deg2rad(-11.25:22.5:348.75),'parent',app.ax);
            app.ax.ThetaZeroLocation = 'top';
            app.ax.ThetaDir = 'clockwise';
            po.Normalization = 'probability';
            app.ax.ThetaTick = 0:22.5:360;
            app.ax.ThetaTickLabel =  app.RTickLable;
        end
        %*********************绘制多高度风能玫瑰图***************************%
        
        function Drow_All_WPD_Rose(app,ws,wd)     %
            theta = deg2rad(0:22.5:360);
            wpd = zeros(1,17);
            ws_size = size(ws);
            
            for index = 1:ws_size(1)
                if (wd(index) > 0 && wd(index) < 11.25) || (wd(index)>348.75 && wd(index) < 360)
                    wpd(1) = wpd(1) + 1/2*app.rho*ws(index)^3;
                elseif (wd(index) > 11.25 && wd(index) < 33.75)
                    wpd(2) = wpd(2) + 1/2*app.rho*ws(index)^3;
                elseif (wd(index) > 33.75 && wd(index) < 56.25)
                    wpd(3) = wpd(3) + 1/2*app.rho*ws(index)^3;
                elseif (wd(index) > 56.25 && wd(index) < 78.75)
                    wpd(4) = wpd(4) + 1/2*app.rho*ws(index)^3;
                elseif (wd(index) > 78.75 && wd(index) < 101.25)
                    wpd(5) = wpd(5) + 1/2*app.rho*ws(index)^3;
                elseif (wd(index) > 101.25 && wd(index) < 123.75)
                    wpd(6) = wpd(6) + 1/2*app.rho*ws(index)^3;
                elseif (wd(index) > 123.75 && wd(index) < 146.25)
                    wpd(7) = wpd(7) + 1/2*app.rho*ws(index)^3;
                elseif (wd(index) > 146.25 && wd(index) < 168.75)
                    wpd(8) = wpd(8) + 1/2*app.rho*ws(index)^3;
                elseif (wd(index) > 168.75 && wd(index) < 191.25)
                    wpd(9) = wpd(9) + 1/2*app.rho*ws(index)^3;
                elseif (wd(index) > 191.25 && wd(index) < 213.75)
                    wpd(10) = wpd(10) + 1/2*app.rho*ws(index)^3;
                elseif (wd(index) > 213.75 && wd(index) < 236.25)
                    wpd(11) = wpd(11) + 1/2*app.rho*ws(index)^3;
                elseif (wd(index) > 236.25 && wd(index) < 258.75)
                    wpd(12) = wpd(12) + 1/2*app.rho*ws(index)^3;
                elseif (wd(index) > 258.75 && wd(index) < 281.25)
                    wpd(13) = wpd(13) + 1/2*app.rho*ws(index)^3;
                elseif (wd(index) > 281.25 && wd(index) < 303.75)
                    wpd(14) = wpd(14) + 1/2*app.rho*ws(index)^3;
                elseif (wd(index) > 303.75 && wd(index) < 326.25)
                    wpd(15) = wpd(15) + 1/2*app.rho*ws(index)^3;
                elseif (wd(index) > 326.25 && wd(index) < 348.75)
                    wpd(16) = wpd(16) + 1/2*app.rho*ws(index)^3;
                end
            end
            wpd(17) = wpd(1);
            wpd = wpd/sum(wpd(1:16));
            polarplot(theta,wpd,'parent',app.ax);
            app.ax.ThetaZeroLocation = 'top';
            app.ax.ThetaDir = 'clockwise';
            app.ax.ThetaTick = 0:22.5:360;
            app.ax.ThetaTickLabel = app.RTickLable;
            hold(app.ax,'on');      %保留绘图区
        end
        %***********************绘制单一风能玫瑰图*************************%
        
        function Drow_WPD_Rose(app,ws,wd)
            wpd = zeros(1,16);
            ws_size = size(ws);
            for index = 1:ws_size(1)
                if (wd(index) > 0 && wd(index) < 11.25) || (wd(index)>348.75 && wd(index) < 360)
                    wpd(1) = wpd(1) + 1/2*app.rho*ws(index)^3;
                elseif (wd(index) > 11.25 && wd(index) < 33.75)
                    wpd(2) = wpd(2) + 1/2*app.rho*ws(index)^3;
                elseif (wd(index) > 33.75 && wd(index) < 56.25)
                    wpd(3) = wpd(3) + 1/2*app.rho*ws(index)^3;
                elseif (wd(index) > 56.25 && wd(index) < 78.75)
                    wpd(4) = wpd(4) + 1/2*app.rho*ws(index)^3;
                elseif (wd(index) > 78.75 && wd(index) < 101.25)
                    wpd(5) = wpd(5) + 1/2*app.rho*ws(index)^3;
                elseif (wd(index) > 101.25 && wd(index) < 123.75)
                    wpd(6) = wpd(6) + 1/2*app.rho*ws(index)^3;
                elseif (wd(index) > 123.75 && wd(index) < 146.25)
                    wpd(7) = wpd(7) + 1/2*app.rho*ws(index)^3;
                elseif (wd(index) > 146.25 && wd(index) < 168.75)
                    wpd(8) = wpd(8) + 1/2*app.rho*ws(index)^3;
                elseif (wd(index) > 168.75 && wd(index) < 191.25)
                    wpd(9) = wpd(9) + 1/2*app.rho*ws(index)^3;
                elseif (wd(index) > 191.25 && wd(index) < 213.75)
                    wpd(10) = wpd(10) + 1/2*app.rho*ws(index)^3;
                elseif (wd(index) > 213.75 && wd(index) < 236.25)
                    wpd(11) = wpd(11) + 1/2*app.rho*ws(index)^3;
                elseif (wd(index) > 236.25 && wd(index) < 258.75)
                    wpd(12) = wpd(12) + 1/2*app.rho*ws(index)^3;
                elseif (wd(index) > 258.75 && wd(index) < 281.25)
                    wpd(13) = wpd(13) + 1/2*app.rho*ws(index)^3;
                elseif (wd(index) > 281.25 && wd(index) < 303.75)
                    wpd(14) = wpd(14) + 1/2*app.rho*ws(index)^3;
                elseif (wd(index) > 303.75 && wd(index) < 326.25)
                    wpd(15) = wpd(15) + 1/2*app.rho*ws(index)^3;
                elseif (wd(index) > 326.25 && wd(index) < 348.75)
                    wpd(16) = wpd(16) + 1/2*app.rho*ws(index)^3;
                end
            end
            po = polarhistogram('BinEdges',deg2rad(-11.25:22.5:348.75),'parent',app.ax,'BinCounts',wpd);
            app.ax.ThetaZeroLocation = 'top';
            app.ax.ThetaDir = 'clockwise';
            po.Normalization = 'probability';
            app.ax.ThetaTick = 0:22.5:360;
            app.ax.ThetaTickLabel = app.RTickLable;
            
        end
        
        %**********************绘制月平均风向玫瑰图**************************%
        function  Drow_Mon_WD_Rose(app,time,wd)
           %绘制月平均风向玫瑰图
            try
                delete(app.ax);
            catch
            end
            month = time.Month;
            app.Rose.AutoResizeChildren = 'off';        %关闭自动调整，才能够执行subplot函数
            app.sub_axes = zeros(1,12);     %保存subplot句柄
            for mon = 1:12
                row = month == mon;
                sub_wd_data = wd(row);        %mon_index从1开始，所以这里的上限为mon_index(mon) - 1
                sub_ax = subplot(3,4,mon,polaraxes,'parent',app.Rose);
                app.sub_axes(mon) = sub_ax;
                po = polarhistogram(deg2rad(sub_wd_data),'BinEdges',deg2rad(-12.5:22.5:348.75),'parent',sub_ax);
                sub_ax.ThetaZeroLocation = 'top';
                sub_ax.ThetaDir = 'clockwise';
                po.Normalization = 'probability';
                sub_ax.ThetaTick = 0:22.5:360;
                sub_ax.ThetaTickLabel = app.RTickLable;
                sub_ax.Title.String = strcat(string(mon),' 月');
            end
            app.Rose.AutoResizeChildren = 'on';
        end
        
        %*************************绘制月平均风能玫瑰图*************************%
        function Drow_Mon_WPD_Rose(app,time,ws_data,wd_data)
            mon_index = ones(1,12);     %存储每个月的数据点数
            wpd = zeros(1,16);      %存储16个风向区间的风功率密度
            app.sub_axes = zeros(1,12);
            month = time.Month;
            for mon = 1:12
                row = find(month == mon);
                wd = wd_data(row,:);
                ws = ws_data(row,:);
                row_size = size(row);
                mon_index(mon) = row_size(1);
                for index = 1:mon_index(mon)-1
                    if (wd(index) > 0 && wd(index) < 11.25) || (wd(index)>348.75 && wd(index) < 360)
                        wpd(1) = wpd(1) + 1/2*app.rho*ws(index)^3;
                    elseif (wd(index) > 11.25 && wd(index) < 33.75)
                        wpd(2) = wpd(2) + 1/2*app.rho*ws(index)^3;
                    elseif (wd(index) > 33.75 && wd(index) < 56.25)
                        wpd(3) = wpd(3) + 1/2*app.rho*ws(index)^3;
                    elseif (wd(index) > 56.25 && wd(index) < 78.75)
                        wpd(4) = wpd(4) + 1/2*app.rho*ws(index)^3;
                    elseif (wd(index) > 78.75 && wd(index) < 101.25)
                        wpd(5) = wpd(5) + 1/2*app.rho*ws(index)^3;
                    elseif (wd(index) > 101.25 && wd(index) < 123.75)
                        wpd(6) = wpd(6) + 1/2*app.rho*ws(index)^3;
                    elseif (wd(index) > 123.75 && wd(index) < 146.25)
                        wpd(7) = wpd(7) + 1/2*app.rho*ws(index)^3;
                    elseif (wd(index) > 146.25 && wd(index) < 168.75)
                        wpd(8) = wpd(8) + 1/2*app.rho*ws(index)^3;
                    elseif (wd(index) > 168.75 && wd(index) < 191.25)
                        wpd(9) = wpd(9) + 1/2*app.rho*ws(index)^3;
                    elseif (wd(index) > 191.25 && wd(index) < 213.75)
                        wpd(10) = wpd(10) + 1/2*app.rho*ws(index)^3;
                    elseif (wd(index) > 213.75 && wd(index) < 236.25)
                        wpd(11) = wpd(11) + 1/2*app.rho*ws(index)^3;
                    elseif (wd(index) > 236.25 && wd(index) < 258.75)
                        wpd(12) = wpd(12) + 1/2*app.rho*ws(index)^3;
                    elseif (wd(index) > 258.75 && wd(index) < 281.25)
                        wpd(13) = wpd(13) + 1/2*app.rho*ws(index)^3;
                    elseif (wd(index) > 281.25 && wd(index) < 303.75)
                        wpd(14) = wpd(14) + 1/2*app.rho*ws(index)^3;
                    elseif (wd(index) > 303.75 && wd(index) < 326.25)
                        wpd(15) = wpd(15) + 1/2*app.rho*ws(index)^3;
                    elseif (wd(index) > 326.25 && wd(index) < 348.75)
                        wpd(16) = wpd(16) + 1/2*app.rho*ws(index)^3;
                    end
                end
                wpd = wpd./sum(wpd);        %计算风功率密度扇区比例
                app.Rose.AutoResizeChildren = 'off';        %关闭自动调整，才能够执行subplot函数
                sub_ax = subplot(3,4,mon,polaraxes,'parent',app.Rose);
                app.sub_axes(mon) = sub_ax;
                po = polarhistogram('BinEdges',deg2rad(-11.25:22.5:348.75),'parent',sub_ax,'BinCounts',wpd);
                sub_ax.ThetaZeroLocation = 'top';
                sub_ax.ThetaDir = 'clockwise';
                po.Normalization = 'probability';
                sub_ax.ThetaTick = 0:22.5:360;
                sub_ax.ThetaTickLabel = app.RTickLable ;
                sub_ax.Title.String = strcat(string(mon),' 月');
            end
        end
        
        %*********计算空气密度************%
        function [rho] = Caculate_Rho(~,P,Temp)
            rho = P*1000/(287*(273+Temp));
        end
        
        %***************************初始化风向玫瑰图*****************************%
        function WD_Rose_Setup(app)
            app.UITable.Visible = 'off';
            app.Table_Title.Visible = 'off';
            app.Rose_Table.Value = 0;
            app.Data_Type.Value = {'风向玫瑰图'};
            app.WD_Height.Enable = 'on';
            app.WS_Height.Enable = 'off';       %设置风速高度不可选
            app.Year_Data.Value = true;     %选中年平均数据
            %********设定动态下拉框参数**********%
            ws_height = circshift(string(sort(app.WS.Properties.CustomProperties.height,'descend')),1);
            wd_height = circshift(string(sort(app.WD.Properties.CustomProperties.height,'descend')),1);
            ws_height(1) = 'all';
            wd_height(1) = 'all';
            app.WS_Height.Items = ws_height;
            app.WD_Height.Items = wd_height;
            app.WD_Height.Value = {'all'};
            lable = zeros(1,app.WD_size(2));        %保存高度标签
            try
                delete(app.ax);
            catch
            end
            try
                 delete(app.sub_axes);
            catch 
            end
            Creat_Axes(app);
            for index = 2:app.WD_size(2)
                Drow_All_WD_Rose(app,app.WD{:,index});
                lable(index-1) = app.WD.Properties.CustomProperties.height(index);
            end
            if app.WD_size(2)>1
                lable = string(lable);
                lable = strcat(lable,' m');
                legend(app.ax,lable);       %设定数据标签
            end
        end
        
        %***************************初始化风向玫瑰图*****************************%
        
        
        function WPD_Rose_Setup(app)
            app.WD_Height.Enable = 'on';
            app.WS_Height.Enable = 'on';        %风速高度可选
            app.Year_Data.Value = true;     %选择年平均数据
            wd_height = circshift(string(sort(app.WD.Properties.CustomProperties.height,'descend')),1);
            ws_height = circshift(string(sort(app.WS.Properties.CustomProperties.height,'descend')),1);
            wd_height(1) = [];
            ws_height(1) = 'all';
            
            app.WS_Height.Items = ws_height;
            app.WD_Height.Items = wd_height;
            app.WD_Height.Value = string(wd_height(1));
            app.WS_Height.Value = {'all'};
            try
                delete(app.ax);    %尝试删除坐标区
                delete(app.sub_axes);
            catch
            end
            Creat_Axes(app);        %创建坐标区
            lable = zeros(1,app.WS_size(2));
            for index = 2:app.WD_size(2)
                if isequal(app.WD.Properties.CustomProperties.height(index) , str2double(app.WD_Height.Value))
                    wd = app.WD{:,index};       %获取风向数据
                end
            end
            for index = 2:app.WS_size(2)
                ws = app.WS{:,index};           %获取风速数据
                Drow_All_WPD_Rose(app,ws,wd);
                lable(index-1) = app.WS.Properties.CustomProperties.height(index);
            end
            if app.WS_size(2)>1
                lable = string(lable);
                lable = strcat(lable,' m');
                legend(app.ax,lable);       %设定数据标签
            end
        end
        
        %在绘制玫瑰图时，获取所选高度的风速数据
        function ws_data = get_ws_data(app)
            for index = 2:app.WS_size(2)
                if isequal(app.WS.Properties.CustomProperties.height(index) , str2double(app.WS_Height.Value))
                    ws_data = app.WS{:,index};
                end
            end
        end
        %在绘制玫瑰图时，获取所选高度的风向数据
        function wd_data = get_wd_data(app)
            for index = 2:app.WD_size(2)
                if isequal(app.WD.Properties.CustomProperties.height(index) , str2double(app.WD_Height.Value))
                    wd_data = app.WD{:,index};
                end
            end
        end
        
        %***********************绘制风向频率表***********************%
        function Drow_WD_Table(app)
            app.Table_Title.Text = {'风向频率表'};
            try
                app.UITable.Data = [];          %清除表的内容
                app.UITable.RowName = [];
                app.UITable.ColumnName = [];
            catch   
            end
            wd_table = zeros(17,app.WD_size(2) - 1);
            height_info = zeros(1,app.WD_size(2) - 1);      %存储数据高度，作为表的列名称
            for wd_index = 2:app.WD_size(2)
                height_info(wd_index - 1) = app.WD.Properties.CustomProperties.height(wd_index);
                wd = app.WD{:,wd_index};
                po = polarhistogram(deg2rad(wd),'BinEdges',deg2rad(-11.25:22.5:348.75));
                fre = po.Values;
                close;      %关闭图窗
                for index = 1:16
                    wd_table(index,wd_index-1) = fre(index)/sum(fre);
                end
                wd_table(17,wd_index-1) = 1;
            end
            app.UITable.Data = wd_table.*100;
            app.UITable.RowName = {'N','NNE','NE','ENE','E','ESE','SE','SSE','S','SSW','SW','WSW','W','WWN','WN','WNW','总计'};
            ColumnName = strcat("Direction  ",string(height_info),"m");
            ColumnName = cellstr(ColumnName);
            app.UITable.ColumnName = ColumnName;
            s = uistyle('HorizontalAlignment','left');
            addStyle(app.UITable,s);
        end
        
        %*************************绘制风能频率表****************************%
        function Drow_WPD_Table(app , wd_data)
            try
                delete(app.Diurnal_Profile_Axes);
                delete(app.ti);
                delete(app.ax);    %尝试删除坐标区，若不存在，则不执行
                delete(app.sub_axes);
            catch
            end
            
            tic
            height_info = zeros(1,app.WS_size(2) - 1);      %存储数据高度，作为表的列名称
            app.Table_Title.Text = strcat("风能频率表","    (Direction    ",app.WD_Height.Value,"m)");
            try
                app.UITable.Data = [];          %清除表的内容
                app.UITable.RowName = [];
                app.UITable.ColumnName = [];
            catch
                
            end
            wd = wd_data;
            wpd_table = zeros(17,app.WS_size(2) - 1);       %存储风能密度表
            for ws_index = 2:app.WS_size(2)
                height_info(ws_index - 1) = app.WS.Properties.CustomProperties.height(ws_index);
                ws = app.WS{:,ws_index};
                ws_data_size = size(ws);
                wpd = zeros(1,16);
                for index = 1:ws_data_size(1)
                    if (wd(index) > 0 && wd(index) < 11.25) || (wd(index)>348.75 && wd(index) < 360)
                        wpd(1) = wpd(1) + 1/2*app.rho*ws(index)^3;
                    elseif (wd(index) > 11.25 && wd(index) < 33.75)
                        wpd(2) = wpd(2) + 1/2*app.rho*ws(index)^3;
                    elseif (wd(index) > 33.75 && wd(index) < 56.25)
                        wpd(3) = wpd(3) + 1/2*app.rho*ws(index)^3;
                    elseif (wd(index) > 56.25 && wd(index) < 78.75)
                        wpd(4) = wpd(4) + 1/2*app.rho*ws(index)^3;
                    elseif (wd(index) > 78.75 && wd(index) < 101.25)
                        wpd(5) = wpd(5) + 1/2*app.rho*ws(index)^3;
                    elseif (wd(index) > 101.25 && wd(index) < 123.75)
                        wpd(6) = wpd(6) + 1/2*app.rho*ws(index)^3;
                    elseif (wd(index) > 123.75 && wd(index) < 146.25)
                        wpd(7) = wpd(7) + 1/2*app.rho*ws(index)^3;
                    elseif (wd(index) > 146.25 && wd(index) < 168.75)
                        wpd(8) = wpd(8) + 1/2*app.rho*ws(index)^3;
                    elseif (wd(index) > 168.75 && wd(index) < 191.25)
                        wpd(9) = wpd(9) + 1/2*app.rho*ws(index)^3;
                    elseif (wd(index) > 191.25 && wd(index) < 213.75)
                        wpd(10) = wpd(10) + 1/2*app.rho*ws(index)^3;
                    elseif (wd(index) > 213.75 && wd(index) < 236.25)
                        wpd(11) = wpd(11) + 1/2*app.rho*ws(index)^3;
                    elseif (wd(index) > 236.25 && wd(index) < 258.75)
                        wpd(12) = wpd(12) + 1/2*app.rho*ws(index)^3;
                    elseif (wd(index) > 258.75 && wd(index) < 281.25)
                        wpd(13) = wpd(13) + 1/2*app.rho*ws(index)^3;
                    elseif (wd(index) > 281.25 && wd(index) < 303.75)
                        wpd(14) = wpd(14) + 1/2*app.rho*ws(index)^3;
                    elseif (wd(index) > 303.75 && wd(index) < 326.25)
                        wpd(15) = wpd(15) + 1/2*app.rho*ws(index)^3;
                    elseif (wd(index) > 326.25 && wd(index) < 348.75)
                        wpd(16) = wpd(16) + 1/2*app.rho*ws(index)^3;
                    end
                end
                wpd = wpd./sum(wpd);
                for index_1 = 1:16
                    wpd_table(index_1,ws_index-1) = wpd(index_1);
                end
                wpd_table(17,ws_index - 1) = sum(wpd);
            end
            app.UITable.Data = wpd_table.*100;
            app.UITable.RowName = {'N','NNE','NE','ENE','E','ESE','SE','SSE','S','SSW','SW','WSW','W','WWN','WN','WNW','总计'};
            ColumnName= strcat("Speed  ",string(height_info),"m");
            ColumnName = cellstr(ColumnName);
            app.UITable.ColumnName = ColumnName;
            s = uistyle('HorizontalAlignment','left');
            addStyle(app.UITable,s);
            toc
        end
        
        function Caculate_Hour_Data(app)
            %将十分钟步长数据转化为小时平均数据
            h = waitbar(0.5,"程序初始化中...");
            %初始化，清空app.WS_Hour...等
            app.WS_Hour = table();
            app.WD_Hour = table();
            app.Temp_Hour = table();
            app.BP_Hour = table();
            app.SD_Hour = table();
            hour_time = app.time;
            hour_time.Minute = 0;       %删除时间的分钟数据
            new_time = unique(hour_time);
             all_data = [app.WS(:,2:end),app.WD(:,2:end),app.Temp(:,2:end),app.BP(:,2:end),app.SD(:,2:end)];     %组合数据
            data_size = size(all_data);
            hour_index = 0;         %小时数
            start_index = 1;        %开始序列
            end_index = 0;          %结束序列
            hour = hour_time.Hour;
            all_data = table2array(all_data);
            for index = 1:data_size(1) - 1          %此处耗时较长，待优化
                end_index = end_index + 1;
                if hour(index + 1) ~= hour(index)
                    hour_index = hour_index + 1;
                    for sub_index = 1:data_size(2)          %此处用冒号求平均时会出错，原因暂不知
                        new_data(hour_index,sub_index) = mean(all_data(start_index:end_index,sub_index));
                    end
                    start_index = end_index + 1;
                end
            end
            %*************************补全最后一个数据************************%
            for sub_index = 1:data_size(2)          %此处用冒号求平均时会出错，原因暂不知
                new_data(hour_index + 1,sub_index) = mean(all_data(start_index:data_size(1),sub_index));
            end

            %**************************************************%
            hour_time = new_time;

            %***********************将小时数据返回给WS、WD、Temp、BP****************%
            app.WS_Hour(:,1) = table(hour_time(:,1));
            app.WD_Hour(:,1) = table(hour_time(:,1));
            app.Temp_Hour(:,1) = table(hour_time(:,1));
            app.BP_Hour(:,1) = table(hour_time(:,1));
            app.SD_Hour(:,1) = table(hour_time(:,1));
            %此处数据有可能为空，对于为空的数据，不执行以下步骤
            new_ws = new_data(:,1:app.WS_size(2) - 1);
            new_wd = new_data(:,app.WS_size(2) : app.WS_size(2) - 1 + app.WD_size(2) - 1);
            new_temp = new_data(:,app.WS_size(2) - 1 + app.WD_size(2) ...
                : app.WS_size(2) - 1 + app.WD_size(2) - 1 + app.Temp_size(2) - 1);
            new_bp = new_data(:,app.WS_size(2) - 1 + app.WD_size(2) - 1 + app.Temp_size(2) ...
                : app.WS_size(2) - 1 + app.WD_size(2) - 1 + app.Temp_size(2) - 1 + app.BP_size(2) - 1);
            new_sd = new_data(:,app.WS_size(2) - 1 + app.WD_size(2) - 1 + app.Temp_size(2) - 1 + app.BP_size(2):end);
            if app.WS_size(2) > 1
                 app.WS_Hour(:,2:app.WS_size(2)) = array2table(new_ws);
            end
            if app.WD_size(2) > 1
                 app.WD_Hour(:,2:app.WD_size(2)) = array2table(new_wd);
            end
            if app.Temp_size(2) > 1
                app.Temp_Hour(:,2:app.Temp_size(2)) = array2table(new_temp);
            end
            if app.BP_size(2) > 1
                 app.BP_Hour(:,2:app.BP_size(2)) = array2table(new_bp);
            end
            if app.SD_size(2) > 1
                app.SD_Hour(:,2:app.SD_size(2)) = array2table(new_sd);
            end
            delete(h);
        end
        
        function Drow_Mon_WS(app)
            %绘制月平均风速曲线
            cla(app.Mon_WS_UIAxes);
            app.Mon_WS = zeros(12,app.WS_size(2) - 1);      %12行数据，每列数据代表一个高度
            data_time = app.WS_Hour{:,1};
            month = data_time.Month;
            for ws_index = 2:app.WS_size(2)
                ws_data = table2array(app.WS_Hour(:,ws_index));
                for mon = 1:12
                    [row ,~] = find(month == mon);
                    app.Mon_WS(mon,ws_index - 1) = mean((ws_data(row,1)));
                end
            end
            for index = 1:app.WS_size(2) - 1
                mon_ws = app.Mon_WS(:,index);
                x = 1:12;
                y = mon_ws';
                xlable = strcat(string(1:12),"月");
                app.Mon_WS_UIAxes.YLim = [0,max(max(app.Mon_WS)) + 3];
                app.Mon_WS_UIAxes.XLim = [0,13];
                plot(x,y,'-o','Parent',app.Mon_WS_UIAxes);     %绘制实现带O标记
                app.Mon_WS_UIAxes.XTick = 1:12;
                app.Mon_WS_UIAxes.XTickMode = 'manual';
                app.Mon_WS_UIAxes.XTickLabel = xlable;
                disableDefaultInteractivity(app.Mon_WS_UIAxes);
                zoom(app.Mon_WS_UIAxes,'off');
                pan(app.Mon_WS_UIAxes,'off');
                hold(app.Mon_WS_UIAxes,'on');
            end
            legend(app.Mon_WS_UIAxes,strcat(string(app.WS.Properties.CustomProperties.height(2:end))," m"));
        end
        
        function Drow_Histogram(app)
            %绘制风速分布直方图
            app.Histogram_WS_Height.Enable = 'on';
            app.Histogram_Table.Visible = 'off';        %设置表格为不可见
            try
                delete(app.Histogram_Axes);
                app.Histogram_Table.Data = [];
            catch
                
            end
            Creat_Histogram_Axes(app);
            
            height = app.WS.Properties.CustomProperties.height;
            height_value = split(app.Histogram_WS_Height.Value,' ');
            for index = 2:app.WS_size(2)
                if isequal(height(index), str2double(height_value(1)))
                    ws_data = app.WS_Hour(:,index);
                end
            end
            ws_data = table2array(ws_data);
            histogram(app.Histogram_Axes,ws_data,0:0.5:ceil(max(ws_data)),'Normalization','probability');
            hold(app.Histogram_Axes,'on');
            k_c_data = app.Weibull_Table.Data;
            k_data = k_c_data(:,1);
            c_data = k_c_data(:,2);
            %极大似然
            f = [];
            v = 0:0.1:ceil(max(ws_data));
            for index = 1:3
                k = k_data(index);
                c = c_data(index);
                f(index,:) = ((k./c).*((v./c).^(k-1))).*exp(-(v./c).^k);
            end
            f = f./2;
            plot(app.Histogram_Axes,v,f(1,:)',v,f(2,:)',v,f(3,:)');
            legend(app.Histogram_Axes,{'实测数据','极大似然法拟合','最小二乘法拟合','WAsP方法拟合'});
        end
        
        function Drow_Year_WS_Diurnal_Profile(app)     
            %绘制年均日变化曲线
            for index = 2:app.WS_size(2)
                try
                    ws_height = split(app.Diurnal_WS_Height.Value," ");
                catch
                end
                if isequal(app.Diurnal_WS_Height.Value,'all')
                    ws_data = app.WS_Hour(:,2:end);
                elseif isequal(str2double(ws_height(1)),app.WS.Properties.CustomProperties.height(index))
                    ws_data = app.WS_Hour(:,index);
                end
            end
            try
                delete(app.ti);
                delete(app.Diurnal_Profile_Axes);
            catch
            end
            ws_size = size(ws_data);
            Creat_Diurnal_Profile_Axes(app);        %创建坐标区
            title(app.Diurnal_Profile_Axes, '年平均风速日变化曲线');
            xlabel(app.Diurnal_Profile_Axes, '时间');
            ylabel(app.Diurnal_Profile_Axes, '风速(m/s)');
            
            ws_hour = zeros(24,app.WS_size(2) - 1);     %列存储日均风速
            ws_data = table2array(ws_data);
            time_data = datetime(app.WS_Hour{:,1});
            hour_data = time_data.Hour;
            for hour = 0:23
                row = find(hour_data == hour);
                ws_hour(hour + 1,:) = mean(ws_data(row,:));
            end
            app.Diurnal_Profile_Axes.XLim = [-1 , 24];
            app.Diurnal_Profile_Axes.YLim = [0,max(max(ws_hour)) + 3];       %让曲线显示位置比较居中
            app.Diurnal_Profile_Axes.XTick = 0:23;
            for index = 1:ws_size(2)
                x = 0:23;
                y = ws_hour(:,index)';
                plot(app.Diurnal_Profile_Axes,x,y,'-o');
                hold(app.Diurnal_Profile_Axes,'on');
            end
            legend(app.Diurnal_Profile_Axes,strcat(string(app.WS.Properties.CustomProperties.height(2:end))," m"));
        end
        
        
        function Drow_Mon_WS_Diurnal_Profile(app)      
            %绘制月均日变化曲线
            for index = 2:app.WS_size(2)
                try
                    ws_height = split(app.Diurnal_WS_Height.Value," ");
                catch
                end
                if isequal(app.Diurnal_WS_Height.Value,'all')
                    ws_data = app.WS_Hour(:,2:end);
                elseif isequal(str2double(ws_height(1)),app.WS.Properties.CustomProperties.height(index))
                    ws_data = app.WS_Hour(:,index);
                end
            end
            
            h = waitbar(0.5,"绘制月平均日变化曲线中...");
            try
                delete(app.ti);
                delete(app.Diurnal_Profile_Axes);
            catch
            end
            time_data = app.WS_Hour{:,1};
            month = time_data.Month;
            hour = time_data.Hour;
            app.ti = tiledlayout(3,4,'Parent',app.Dirunal_Profile_Panel);          %创建3*4布局
            title(app.ti,"月平均风速日变化曲线",'FontSize',12,'FontName','Helvetica','FontWeight','bold','Color',[0 0 0]);
            xlabel(app.ti,"时间");
            ylabel(app.ti,"风速(m/s)");
            ws_data = table2array(ws_data);
            for mon = 1:12
                ws_mon = zeros(24,app.WS_size(2) - 1);
                for hour_index = 0:23
                    row = find(hour == hour_index & month == mon);
                    ws_mon(hour_index + 1,:) = mean(ws_data(row,:));
                end
                x = 0:23;
                y = ws_mon';
                sub_ax = nexttile(app.ti);
                plot(sub_ax,x,y);
                hold(sub_ax,'on');
                sub_ax.Title.String = strcat(string(mon),' 月');
                sub_ax.XLim = [-1 , 24];
                sub_ax.YLim = [0,max(max(ws_mon)) + 3];       %让曲线显示位置比较居中
            end
            delete(h);
        end
        
        function Drow_Year_WPD_Diurnal_Profile(app)
            for index = 2:app.WS_size(2)
                try
                    ws_height = split(app.Diurnal_WS_Height.Value," ");
                catch
                end
                if isequal(app.Diurnal_WS_Height.Value,'all')
                    ws_data = app.WS_Hour(:,2:end);
                elseif isequal(str2double(ws_height(1)),app.WS.Properties.CustomProperties.height(index))
                    ws_data = app.WS_Hour(:,index);
                end
            end
            
            try
                delete(app.Diurnal_Profile_Axes);
                delete(app.ti);
            catch
            end
            ws_size = size(ws_data);
            Creat_Diurnal_Profile_Axes(app);        %创建坐标区
            title(app.Diurnal_Profile_Axes, '年平均风功率密度日变化曲线');
            xlabel(app.Diurnal_Profile_Axes, '时间');
            ylabel(app.Diurnal_Profile_Axes, '风功率(w/m^2)');
            wpd_hour = zeros(24,app.WS_size(2) - 1);     %列存储日均风速
            ws_data = table2array(ws_data);
            wpd_data = 1/2.*app.rho.*ws_data.^3;
            time_data = datetime(app.WS_Hour{:,1});
            hour_data = time_data.Hour;
            
            for hour = 0:23
                row = find(hour_data == hour);
                wpd_hour(hour + 1,:) = mean(wpd_data(row,:));
            end
            app.Diurnal_Profile_Axes.XLim = [-1 , 24];
            app.Diurnal_Profile_Axes.YLim = [0,max(max(wpd_hour)) + min(min(wpd_hour))];       %让曲线显示位置比较居中
            app.Diurnal_Profile_Axes.XTick = 0:23;
            for index = 1:ws_size(2)
                x = 0:23;
                y = wpd_hour(:,index)';
                plot(app.Diurnal_Profile_Axes,x,y,'-o');
                hold(app.Diurnal_Profile_Axes,'on');
            end
            legend(app.Diurnal_Profile_Axes,strcat(string(app.WS.Properties.CustomProperties.height(2:end))," m"));
        end
        
        function Drow_Mon_WPD_Diurnal_Profile(app)
            for index = 2:app.WS_size(2)
                try
                    ws_height = split(app.Diurnal_WS_Height.Value," ");
                catch
                end
                if isequal(app.Diurnal_WS_Height.Value,'all')
                    ws_data = app.WS_Hour(:,2:end);
                elseif isequal(str2double(ws_height(1)),app.WS.Properties.CustomProperties.height(index))
                    ws_data = app.WS_Hour(:,index);
                end
            end
            
            h = waitbar(0.5,"绘制月平均风功率密度日变化曲线中...");
            try
                delete(app.ti);
                delete(app.Diurnal_Profile_Axes);
            catch
            end
            time_data = app.WS_Hour{:,1};
            month = time_data.Month;
            hour = time_data.Hour;
            app.ti = tiledlayout(3,4,'Parent',app.Dirunal_Profile_Panel);          %创建3*4布局
            title(app.ti,"月平均风功率密度日变化曲线",'FontSize',12,'FontName','Helvetica','FontWeight','bold','Color',[0 0 0]);
            xlabel(app.ti,"时间");
            ylabel(app.ti,"风功率密度(w/m^2)");
            ws_data = table2array(ws_data);
            wpd_data = 1/2.*app.rho.*ws_data.^3;
            for mon = 1:12
                wpd_mon = zeros(24,app.WS_size(2) - 1);
                for hour_index = 0:23
                    row = find(hour == hour_index & month == mon);
                    wpd_mon(hour_index + 1,:) = mean(wpd_data(row,:));
                end
                x = 0:23;
                y = wpd_mon';
                sub_ax = nexttile(app.ti);
                plot(sub_ax,x,y);
                hold(sub_ax,'on');
                sub_ax.Title.String = strcat(string(mon),' 月');
                sub_ax.XLim = [-1 , 24];
                sub_ax.YLim = [0,max(max(wpd_mon)) + min(min(wpd_mon))];       %让曲线显示位置比较居中
            end
            delete(h);
        end
        
        function Creat_Diurnal_Profile_Axes(app)
            app.Diurnal_Profile_Axes = uiaxes(app.Dirunal_Profile_Panel);
            app.Diurnal_Profile_Axes.TitleFontWeight = 'bold';
            app.Diurnal_Profile_Axes.Position = [15,16,819,498];
        end
        
        function Diurnal_Setup(app)
            %初始化日变化曲线界面
            ws_height = circshift(string(sort(app.WS.Properties.CustomProperties.height,'descend')),1);
            app.Diurnal_WS_Height.Items = strcat(string(ws_height)," m");
            app.Diurnal_WS_Height.Items(1) = {'all'};
            try
                delete(app.ax);
                delete(app.sub_axes);
                delete(app.Diurnal_Profile_Axes);
                delete(app.ti);
            catch  
            end
            app.Year_WS_Diurnal.Value = 1;      %初始状态为绘制年平均风速日变化曲线
            app.Diurnal_WS_Height.Value = {'all'};
            Creat_Axes(app);
            Drow_Year_WS_Diurnal_Profile(app);
            
        end
        
        function  Drow_Histogram_Table(app)
            %绘制风速分布表格
            app.hist_data = [];
            app.Histogram_WS_Height.Enable = 'off';
            try
                delete(app.Histogram_Axes);
                app.Histogram_Table.Data = [];
                app.Histogram_Table.Visible = 'on';
            catch
            end
            app.Histogram_Table.Visible = 'on';
            app.Table_Title.Text = {'风向频率表'};
            try
                app.UITable.Data = [];          %清除表的内容
                app.UITable.RowName = [];
                app.UITable.ColumnName = [];
            catch
            end
            height = app.WS.Properties.CustomProperties.height(:,2:end);
            column = strcat(string(height)," m");
            app.Histogram_Table.ColumnName = column;
            max_row = ceil(max(max(table2array(app.WS_Hour(:,2:end)))));
            for index = 2:app.WS_size(2)
                ws_data = app.WS_Hour(:,index);
                ws_data = table2array(ws_data);
                hi =  histogram(ws_data,0:0.5:(max_row + 0.5),'Normalization','probability');
                app.hist_data(index - 1,:) = hi.Values;
                close;
            end
            app.Histogram_Table.Data = (app.hist_data.*100)';
            app.Histogram_Table.Data(end,:) = sum(app.hist_data').*100;
            app.Histogram_Table.RowName = string(0.5:0.5:max_row + 0.5);
            app.Histogram_Table.RowName(end) = {"合计"};
            s = uistyle('HorizontalAlignment','left');
            addStyle(app.Histogram_Table,s);
        end
        
        function Creat_Histogram_Axes(app)
            app.Histogram_Axes = uiaxes(app.Histogram_Panel);
            title(app.Histogram_Axes, '风速分布')
            xlabel(app.Histogram_Axes, '风速(m/s)')
            ylabel(app.Histogram_Axes, '频率')
            app.Histogram_Axes.PlotBoxAspectRatio = [1.48428835489834 1 1];
            app.Histogram_Axes.TitleFontWeight = 'bold';
            app.Histogram_Axes.Position = [28 16 690 489];
        end
        
        function Histogram_UI_Setup(app)
            app.Table_Switch.Value = 0;
            app.Histogram_WS_Height.Enable = 'on';
            try
                delete(app.Histogram_Axes);
                app.Histogram_Table.Visible = 'off';
            catch  
            end
            Creat_Histogram_Axes(app);
            height = app.WS.Properties.CustomProperties.height;
            items = strcat(string(height(2:end))," m");
            app.Histogram_WS_Height.Items = items;
            app.Histogram_WS_Height.Value = app.Histogram_WS_Height.Items(1);
        end
        
        function Histogram_Setup(app)
            height = app.WS.Properties.CustomProperties.height;
            height_value = split(app.Histogram_WS_Height.Value,' ');
            for index = 2:app.WS_size(2)
                if isequal(height(index), str2double(height_value(1)))
                    ws_data = app.WS_Hour(:,index);
                end
            end
            
            ws_data = table2array(ws_data);
            histogram(app.Histogram_Axes,ws_data,0:0.5:ceil(max(ws_data)),'Normalization','probability');
            hold(app.Histogram_Axes,'on');
            k_c_data = app.Weibull_Table.Data;
            k_data = k_c_data(:,1);
            c_data = k_c_data(:,2);
            %极大似然
            f = [];
            v = 0:0.1:ceil(max(ws_data));
            for index = 1:3
                k = k_data(index);
                c = c_data(index);
                f(index,:) = ((k./c).*((v./c).^(k-1))).*exp(-(v./c).^k);
            end
            f = f./2;
            plot(app.Histogram_Axes,v,f(1,:)',v,f(2,:)',v,f(3,:)');
            legend(app.Histogram_Axes,{'实测数据','极大似然法拟合','最小二乘法拟合','WAsP方法拟合'});
        end
        
        function Drow_Weibull_Table(app)
            %绘制威布尔分布参数表格
            k = [];     %分别存储极大似然法、最小二乘法法、WAsP方法计算得到的k、c
            c = [];      
            app.Weibull_Table.Data = [];
            height = app.WS.Properties.CustomProperties.height;
            height_value = split(app.Histogram_WS_Height.Value,' ');
            for index = 2:app.WS_size(2)
                if isequal(height(index), str2double(height_value(1)))
                    ws_data = app.WS_Hour(:,index);
                end
            end
            ws_data = table2array(ws_data);
            %极大似然法
            [k(1),c(1)] = Maximum_Likelihood(app,ws_data);
            %最小二乘法
            [k(2),c(2)] = Least_Squares(app,ws_data);
            %WAsP法
            [k(3),c(3)] = WAsP(app,ws_data);
            data = [k',c'];
            app.Weibull_Table.Data = data;
            app.Weibull_Table.ColumnName = {'k','c'};
            app.Weibull_Table.RowName = {'极大似然法','最小二乘法','WAsP方法'};
            s = uistyle('HorizontalAlignment','left');
            addStyle(app.Weibull_Table,s);
        end
        function [k,c] =  Maximum_Likelihood(app,ws_data)
            %极大似然法计算k、c
            %             ws = ws_data;
            %             e = 0.0001;      %计算精度
            %             k = 1;      %迭代一次后的值
            %             k0 = 0;     %初始值
            %             ws_size = size(ws);
            %             n = ws_size(1);
            %             while abs(k-k0) > e
            %                 k0 = k;
            %                 a = sum((ws.^k).*(log(ws)))./sum(ws.^k);      %公式前半部分
            %                 b = sum(log(ws))./n;
            %                 k = 1./(a-b);
            %             end
            %             c = ((1./n).*sum(ws.^k)).^(1./k);
            
            %发现MATLAB能够使用wblfit来计算威布尔参数，且使用极大似然法,运算速度似乎快很多
            parmhat = wblfit(ws_data);
            k = parmhat(2);
            c = parmhat(1);
        end
        
        function [k,c] = Least_Squares(app,ws_data)
            %最小二乘法
            ws = ws_data;
            ws_size = size(ws);
            ta = tabulate(ws);
            ta_size = size(ta);
            for index = 1:ta_size(1)
                fre(index) = sum(ta(1:index,3));        %累积频率分布
            end
            v = ta(:,1);
            F_v = fre./100;
            F_v = F_v';
            n = ws_size(1);
            x = log(v);
            y = log(log(1./(1-F_v)));
            a = sum((x-mean(x)).*(y-mean(y)))./(sum((x-mean(x)).^2));
            b = mean(y)-a.*mean(x);
            k = a;
            c = exp(-(b./k));
        end
        
        function [k,c] = WAsP(app,ws_data)
            %WAsP方法
            F_v = [];
            ws = ws_data;
            ws_size = size(ws);
            n = ws_size(1);
            ta = tabulate(ws);
            ta_size = size(ta);
            for index = 1:ta_size(1)
                fre(index) = sum(ta(1:index,3));
            end
            F_v = (fre./100)';  %实测数据累积频率分布
            row = find(ws>mean(ws));
            row_size = size(row);
            a = 0;
            b = 10;
            index= 0;
            k = 5;
            X = row_size(1)./ws_size(1);
            while abs(f(app,a,ws, X) - f(app,b,ws,X)) > 0.001
                index = index + 1;
                k = (a + b)./2;
                f_a = f(app,a,ws ,X);
                f_k = f(app,k,ws , X);
                f_b = f(app,b,ws, X);
                if f_a*f_k<0
                    b = k;
                elseif f_b*f_k<0
                    a = k;
                end
            end
            c = (sum(ws.^3)./(n.*gamma(3./k + 1))).^(1./3);
        end
        
        function f = f(~,k , ws , X)
            ws_size = size(ws);
            temp = mean(ws)./(sum(ws.^3)./(ws_size(1).*gamma((3./k) + 1))).^(1/3);
            a = temp.^k;
            b = -log(X);
            f = a - b;
        end
        
        function summary_UI_Setup(app)
            %概况界面初始化
            Drow_Mon_WS(app);       %更新月平均风速曲线
            temp_size = size(app.Temp);
            if temp_size(2) > 1     %如果表中仅有时间数据，不执行以下程序
                app.Mean_Temp_Data.Text = strcat(sprintf('%0.1f',mean(app.Temp{:,2})),' ℃');
            end
            
            %显示气压
            bp_size = size(app.BP);
            if bp_size(2) > 1        %如果表中仅有时间数据，不执行以下程序
                app.Mean_BP_Data.Text = strcat(sprintf('%0.2f',(mean(app.BP{:,2}))),' kpa');
            end
            
            %计算空气密度
            if bp_size(2) > 1 && temp_size(2) > 1
                app.rho = Caculate_Rho(app,(mean(app.BP{:,2})),mean(app.Temp{:,2}));
            else
                app.rho = 1.225;
            end
            app.Mean_rho_data.Text= strcat(sprintf('%0.3f',app.rho),' kg/m^3');
            %显示最高处测风仪平均风速
            app.Mean_WS_Highest.Tooltip = '最高处测风仪的MoMM风速';
            if app.WS_size(2)>1
                height = max(app.WS.Properties.CustomProperties.height);
                app.Mean_WS_Highest.Text = strcat(num2str(height),'m平均风速');
                MoMM = caculate_ws(app.WS,app.WS_Hour,height);        %调用computews函数计算平均风速，并将计算结果返回给MoMM
                app.Mean_WS_Highest_Data.Text = strcat(MoMM,' m/s');
            end
            
            %显示50m高度风功率密度
            wpd_size = app.WS_size;
            column = 0;
            for index = 2:wpd_size(2)
                if app.WS.Properties.CustomProperties.height(index) == 50
                    column = index;
                end
            end
            if wpd_size(2)>1 && column ~=0
                if isequal(bp_size(2) , 1) || isequal(temp_size(2), 1)
                    wpd = caculate_wpd(app.WS,app.WS_Hour,50,0,0);
                else
                    wpd = caculate_wpd(app.WS,app.WS_Hour,50,mean(app.BP{:,2}),mean(app.Temp{:,2}));
                end
                app.Mean_WPD_50_Data.Text = strcat(num2str(wpd),'W/m^2');
            else
                wpd = -1;
            end
            %显示风功率密度等级，以50m高度为基准
            if wpd == -1
                app.Wind_Power_Class_Data.Text = '';
            elseif wpd>0 && wpd<200
                app.Wind_Power_Class_Data.Text = '1';
            elseif wpd<300
                app.Wind_Power_Class_Data.Text = '2';
            elseif wpd<400
                app.Wind_Power_Class_Data.Text = '3(较好)';
            elseif wpd<500
                app.Wind_Power_Class_Data.Text = '4(好)';
            elseif wpd<600
                app.Wind_Power_Class_Data.Text = '5(很好)';
            elseif wpd<800
                app.Wind_Power_Class_Data.Text = '6(很好)';
            elseif wpd <2000
                app.Wind_Power_Class_Data.Text = '7(很好)';
            end
            
            %计算风切变系数
            height_size = size(app.WS.Properties.CustomProperties.height);      %第一列数据为0，与风速数据第一列对应
            wind_speed = zeros(1,height_size(2)-1);
            for height_index = 2:height_size(2)
                wind_speed(height_index - 1) = mean(app.WS{:,height_index});
            end
            %*******拟合幂指数********%
            ws_height_size = size(app.WS.Properties.CustomProperties.height);
            if ws_height_size(2) > 2        %如何有超过两个高度的风速数据，计算风切变系数
                %拟合公式为 ln(U(z)) = a*ln(z) + ln(b)
                x_data = app.WS.Properties.CustomProperties.height(2:height_size(2));
                y_data = wind_speed;
                x_data = log(x_data);
                y_data = log(y_data);
                [xData, yData] = prepareCurveData( x_data, y_data );        %x_data为高度数据，y_data为风速数据
                ft = fittype( 'a*x + b', 'independent', 'x', 'dependent', 'y' );
                opts = fitoptions( 'Method', 'NonlinearLeastSquares' );
                [fitresult, ~] = fit( xData, yData, ft, opts );        %fitresult存储拟合结果，gof存储相关系数
                app.Power_Law_Data.Text = sprintf('%0.4f',fitresult.a);
            end           
            %绘制月平均风速曲线
            Drow_Mon_WS(app);
        end
end
    %*********************************************************%

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %隐藏数据概况组件
            app.Sub_Summary.Visible = 'off';
            app.TabGroup.Visible = 'off';
            app.UITable.Visible = 'off';
        end

        % Menu selected function: File_Open
        function File_OpenMenuSelected(app, event)
            app.import_data_app = import_data(app);
            waitfor(app.import_data_app);       %等待数据导入完成
            
            %x显示数据概况组件
            app.TabGroup.Visible = 'on';
            app.Sub_Summary.Visible = 'on';
            %******显示数据概况******%
            %显示时间
            date_format = 'yy/mm/dd HH:MM';
            app.Start_Time_Data.Text = datestr(app.time(1),date_format);
            app.End_Time_Data.Text = datestr(app.time(end)+minutes(10),date_format);
            %****************将十分钟数据转化为小时数据******************%
            if app.WS_size(2) > 1
                Caculate_Hour_Data(app);
            end
            summary_UI_Setup(app);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            switch selectedTab.Title
                case  '数据概况'        %因为数据概况从程序打开就开始执行，所以这里为空
                    summary_UI_Setup(app);
                case '风玫瑰图'
                    if app.WD_size(2) > 1
                        WD_Rose_Setup(app);     %初始状态为所有风向高度的风向玫瑰图
                    end
                case '日变化曲线'
                    Diurnal_Setup(app); 
                case '直方图'
                    Histogram_UI_Setup(app);
                    Drow_Weibull_Table(app);  
                    Histogram_Setup(app); 
            end
        end

        % Value changed function: Data_Type
        function Data_TypeValueChanged(app, event)
            %数据类型发生改变后，风向高度会变成指定值，然后绘制出对应图像
            value = app.Data_Type.Value;
            if (isequal(value , '风向玫瑰图') && app.WD_size(2) > 1 && app.Rose_Table.Value == 0 )    %选中风向玫瑰图，且有风向数据，绘制所有高度风向玫瑰图
                WD_Rose_Setup(app);
            end
            
            if (isequal(value , '风能玫瑰图') && app.WS_size(2) > 1 && app.WD_size(2) > 1)      %选中风能玫瑰图，且有风向和风速数据，绘制所有风能玫瑰图
                WPD_Rose_Setup(app);
            end
            
            if (isequal(value , '风能玫瑰图') && app.Rose_Table.Value == 1)
                app.WS_Height.Enable = 'off';
                app.WD_Height.Enable = 'on';
                try
                    delete(app.ax);
                catch  
                end
                try
                    delete(app.sub_axes);
                catch   
                end
                wd_height = circshift(string(sort(app.WD.Properties.CustomProperties.height,'descend')),1);
                wd_height(1) = [];
                app.WD_Height.Items = wd_height;
                app.WD_Height.Value = string(wd_height(1));
                wd_data = get_wd_data(app);
                Drow_WPD_Table(app,wd_data);
            end
            if isequal(app.Data_Type.Value , '风向玫瑰图') && app.Rose_Table.Value == 1 && app.WD_size(2) > 1
                app.WD_Height.Enable = 'off';
                Drow_WD_Table(app);
            end
          
        end

        % Value changed function: WD_Height
        function WD_HeightValueChanged(app, event)
            value = app.WD_Height.Value;        %数据高度
            if (isequal(app.Data_Type.Value,'风向玫瑰图') && isequal(value , 'all')...
                    && isequal(app.Time_Type.SelectedObject.Text, '年平均数据'))
                try
                    delete(app.ax);         %删除坐标轴
                    delete(app.sub_axes);
                catch
                end
                Creat_Axes(app);
                lable = zeros(1,app.WD_size(2));
                for index = 2:app.WD_size(2)
                    Drow_All_WD_Rose(app,app.WD{:,index});
                    lable(index-1) = app.WD.Properties.CustomProperties.height(index);
                end
                if app.WD_size(2)>1
                    lable = string(lable);
                    lable = strcat(lable,' m');
                    legend(app.ax,lable);       %设定数据标签
                end
            end
            
            if isequal(app.Data_Type.Value , '风向玫瑰图') && ~(isequal(value, 'all') ...
                    && isequal(app.Time_Type.SelectedObject.Text, '年平均数据'))
                try
                    delete(app.ax);
                    delete(app.sub_axes);       %删除坐标轴
                catch
                end
                wd_data = get_wd_data(app);
                Creat_Axes(app);
                Drow_WD_Rose(app,wd_data);
            end
            
            if (isequal(app.Data_Type.Value,'风向玫瑰图') && ~isequal(value , 'all')...
                    && isequal(app.Time_Type.SelectedObject.Text, '月平均数据')) && app.WD_size(2) > 1
                try
                    delete(app.ax);
                catch
                end
                try
                    delete(app.sub_axes);        %删除坐标轴
                catch 
                end
                wd_data = get_wd_data(app);     %调用函数获取风向数据
                h = waitbar(0.5,"风向玫瑰图绘制中...");
                Drow_Mon_WD_Rose(app,app.time,wd_data);
                close(h);
            end
            
            if isequal(app.Data_Type.Value , '风能玫瑰图') && isequal(app.WS_Height.Value , 'all') ...
                    && isequal(app.Time_Type.SelectedObject.Text, '年平均数据') && app.Rose_Table.Value == 0
                try
                    delete(app.ax);
                    delete(app.sub_axes);        %删除坐标轴
                catch
                end
                Creat_Axes(app);
                lable = zeros(1,app.WS_size(2)-1);
                wd_data = get_wd_data(app);
                for index = 2:app.WS_size(2)
                    ws_data = app.WS{:,index};      %获取所选高度风速数据
                    Drow_All_WPD_Rose(app,ws_data,wd_data);
                    lable(index-1) = app.WS.Properties.CustomProperties.height(index);
                end
                
                if app.WS_size(2)>1
                    lable = string(lable);
                    lable = strcat(lable,' m');
                    legend(app.ax,lable);       %设定数据标签
                end
            end
            
            if isequal(app.Data_Type.Value , '风能玫瑰图') && ~isequal(app.WS_Height.Value , 'all')...
                    &&isequal(app.Time_Type.SelectedObject.Text, '年平均数据')
                try
                    delete(app.ax);
                    delete(app.sub_axes);        %删除坐标轴
                catch
                end
                ws_data = get_ws_data(app);      %获取所选高度风向数据
                wd_data = get_wd_data(app);
                Creat_Axes(app);
                Drow_WPD_Rose(app,ws_data,wd_data);
            end
            
            if isequal(app.Data_Type.Value , '风能玫瑰图') && ~isequal(app.WS_Height.Value , 'all')...
                    &&isequal(app.Time_Type.SelectedObject.Text, '月平均数据')
                ws_data = get_ws_data(app);
                wd_data = get_wd_data(app);
                try
                    delete(app.ax);
                    delete(app.sub_axes);        %删除坐标轴
                catch
                end
                h = waitbar(0.5,"风能玫瑰图绘制中...");
                Drow_Mon_WPD_Rose(app,app.time,ws_data,wd_data);
                close(h);
            end
            
            if isequal(app.Data_Type.Value , '风能玫瑰图') && app.Rose_Table.Value == 1 && app.WD_size(2) > 1 && app.WS_size(2) > 1
                wd_data = get_wd_data(app);
                Drow_WPD_Table(app,wd_data);
            end
            

        end

        % Value changed function: WS_Height
        function WS_HeightValueChanged(app, event)
            value = app.WS_Height.Value;
            
            if isequal(app.Data_Type.Value , '风能玫瑰图') && isequal(value , 'all') ...
                    && isequal(app.Time_Type.SelectedObject.Text, '年平均数据')
                try
                    delete(app.ax);
                    delete(app.sub_axes);       %删除坐标轴
                catch
                end
                wd_data = get_wd_data(app);
                Creat_Axes(app);
                lable = zeros(1,app.WS_size(2));
                for index = 2:app.WS_size(2)
                    ws_data = app.WS{:,index};
                    Drow_All_WPD_Rose(app,ws_data,wd_data);
                    lable(index-1) = app.WS.Properties.CustomProperties.height(index);
                end
                if(app.WS_size(2)>1)
                    lable = string(lable);
                    lable = strcat(lable,' m');
                    legend(app.ax,lable);       %设定数据标签
                end
            end
            
            if isequal(app.Data_Type.Value , '风能玫瑰图') && ~isequal(value , 'all') ...
                    && isequal(app.Time_Type.SelectedObject.Text, '年平均数据')    %绘制单一风能玫瑰图
                ws_data = get_ws_data(app);
                wd_data = get_wd_data(app);
                try
                    delete(app.ax);
                    delete(app.sub_axes);        %删除坐标轴
                catch
                end
                Creat_Axes(app);
                Drow_WPD_Rose(app,ws_data,wd_data)
            end
            
            if isequal(app.Data_Type.Value , '风能玫瑰图') && isequal(app.Time_Type.SelectedObject.Text, '月平均数据')
                ws_data = get_ws_data(app);
                wd_data = get_wd_data(app);
                try
                    delete(app.ax);
                    delete(app.sub_axes);        %删除坐标轴
                catch
                end
                h = waitbar(0.5,"风向玫瑰图绘制中...");
                Drow_Mon_WPD_Rose(app,app.time,ws_data,wd_data);
                close(h);
            end
            
            
        end

        % Selection changed function: Time_Type
        function Time_TypeSelectionChanged(app, event)
            selectedButton = app.Time_Type.SelectedObject;
            
            if app.WS_size(2) > 1
                ws_height = circshift(string(sort(app.WS.Properties.CustomProperties.height,'descend')),1);
                ws_height(1) = [];
            end
            if app.WD_size(2) > 1
                wd_height = circshift(string(sort(app.WD.Properties.CustomProperties.height,'descend')),1);
                wd_height(1) = [];
            end
            
            if isequal(selectedButton.Text, '年平均数据') && isequal(app.Data_Type.Value , '风向玫瑰图')
                WD_Rose_Setup(app);
            end
            if isequal(selectedButton.Text, '年平均数据') && isequal(app.Data_Type.Value , '风能玫瑰图')
                WPD_Rose_Setup(app);
            end
            
            
            if isequal(selectedButton.Text,'月平均数据') && app.WD_size(2) > 1 ...
                    && isequal(app.Data_Type.Value , '风向玫瑰图')      %选中月平均数据，绘制月平均玫瑰图
                app.WD_Height.Value = string(wd_height(1));      %当Time_Type变为月平局数据时，将WD_Height高度设置为最高
                h = waitbar(0.5,"风向玫瑰图绘制中...");
                wd_data = get_wd_data(app);
                try
                    delete(app.ax);     %删除坐标轴
                catch
                end
                try 
                    delete(app.sub_axes);
                catch 
                end
                app.WD_Height.Items(1) = [];
                Drow_Mon_WD_Rose(app,app.time,wd_data);
                close(h);
            end
            if isequal(selectedButton.Text, '月平均数据') && isequal(app.Data_Type.Value , '风能玫瑰图') && app.WS_size(2) > 1
                app.WS_Height.Items(1) = [];
                app.WD_Height.Value = string(wd_height(1));
                app.WS_Height.Value = string(ws_height(1));
                wd_data = get_wd_data(app);
                ws_data = get_ws_data(app);
                
                try
                    delete(app.ax);     %删除坐标轴
                    delete(app.sub_axes);
                catch
                end
                
                h = waitbar(0.5,"风能玫瑰图绘制中...");
                Drow_Mon_WPD_Rose(app,app.time,ws_data,wd_data);
                close(h);
            end
            
            
        end

        % Value changed function: Rose_Table
        function Rose_TableValueChanged(app, event)
            %绘制频率表格
            value = app.Rose_Table.Value;
            if value == 1
                app.Data_Type.Value = {'风向玫瑰图'};
                app.UITable.Visible = 'on';
                app.Table_Title.Visible = 'on';
                app.WD_Height.Enable = 'off';
                app.WS_Height.Enable = 'off';
                app.Year_Data.Enable = 'off';
                app.Month_Data.Enable = 'off';
                Drow_WD_Table(app);
                app.Table_Title.Text = '风向频率表';
                try
                    delete(app.ax);
                    delete(app.sub_axes);
                catch
                    
                end
            end
            
            if value == 0
                app.UITable.Visible = 'off';
                app.Table_Title.Visible = 'off';
                app.WD_Height.Enable = 'on';
                app.WS_Height.Enable = 'on';
                app.Year_Data.Enable = 'on';
                app.Month_Data.Enable = 'on';
                WD_Rose_Setup(app);
            end
        end

        % Menu selected function: Rationality
        function RationalityMenuSelected(app, event)
            app.rationality_app = rationality_verification(app);
            
        end

        % Menu selected function: Correlation
        function CorrelationMenuSelected(app, event)
           app.correlation_app = correlation_verification(app);
           
        end

        % Menu selected function: Trendiness
        function TrendinessMenuSelected(app, event)
            app.trendiness_app = trendiness_verification(app);
            
        end

        % Value changed function: Histogram_WS_Height
        function Histogram_WS_HeightValueChanged(app, event)
            if app.Table_Switch.Value == 0
                Drow_Weibull_Table(app);
                Drow_Histogram(app);

            elseif app.Table_Switch.Value == 1
                Drow_Histogram_Table(app);
            end
        end

        % Selection changed function: Time_Switch
        function Time_SwitchSelectionChanged(app, event)
            selectedButton = app.Time_Switch.SelectedObject;
            if selectedButton.Text == "年平均风速日变化"
                Drow_Year_WS_Diurnal_Profile(app);
            elseif selectedButton.Text == "年平均风功率日变化"
                Drow_Year_WPD_Diurnal_Profile(app);
            elseif selectedButton.Text == "月平均风速日变化"
                Drow_Mon_WS_Diurnal_Profile(app);
            elseif selectedButton.Text == "月平均风功率日变化"
                Drow_Mon_WPD_Diurnal_Profile(app);
            end
            
        end

        % Value changed function: Diurnal_WS_Height
        function Diurnal_WS_HeightValueChanged(app, event)
               selectedButton = app.Time_Switch.SelectedObject;
            if selectedButton.Text == "年平均风速日变化"
                Drow_Year_WS_Diurnal_Profile(app);
            elseif selectedButton.Text == "年平均风功率日变化"
                Drow_Year_WPD_Diurnal_Profile(app);
            elseif selectedButton.Text == "月平均风速日变化"
                Drow_Mon_WS_Diurnal_Profile(app);
            elseif selectedButton.Text == "月平均风功率日变化"
                Drow_Mon_WPD_Diurnal_Profile(app);
            end
        end

        % Value changed function: Table_Switch
        function Table_SwitchValueChanged(app, event)
            value = app.Table_Switch.Value;
            %当按钮按下时，执行以下程序
            if value == 1
                Drow_Histogram_Table(app);
            end
            if value == 0
                Drow_Histogram(app);
            end
        end

        % Menu selected function: Data_Interpolation
        function Data_InterpolationMenuSelected(app, event)
            app.interpolation_app = data_interpolation(app);
        end

        % Menu selected function: Wind_Shear
        function Wind_ShearMenuSelected(app, event)
            app.wind_shear_app = wind_shear_analyze(app);   %调用风廓线分析程序
        end

        % Menu selected function: Turbulence
        function TurbulenceMenuSelected(app, event)
            if app.SD_size(2) > 1
                app.turbulence_app = turbulence_analyze(app);       %调用湍流分析程序
            else
                errordlg("未发现风速标准差数据");
            end
        end

        % Menu selected function: File_Export
        function File_ExportMenuSelected(app, event)
            app.export_data_app = export_data(app);
        end

        % Menu selected function: MCP
        function MCPMenuSelected(app, event)
            app.mcp_app = data_revision(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.mainapp = uifigure('Visible', 'off');
            app.mainapp.Name = '风资源分析程序';
            app.File = uimenu(app.mainapp);
            app.File.Text = '文件';
            app.File_Open = uimenu(app.File);
            app.File_Open.MenuSelectedFcn = createCallbackFcn(app, @File_OpenMenuSelected, true);
            app.File_Open.Text = '打开';
            app.File_Export = uimenu(app.File);
            app.File_Export.MenuSelectedFcn = createCallbackFcn(app, @File_ExportMenuSelected, true);
            app.File_Export.Text = '导出';
            app.Data_Verification = uimenu(app.mainapp);
            app.Data_Verification.Text = '数据验证';
            app.Rationality = uimenu(app.Data_Verification);
            app.Rationality.MenuSelectedFcn = createCallbackFcn(app, @RationalityMenuSelected, true);
            app.Rationality.Text = '合理性验证';
            app.Correlation = uimenu(app.Data_Verification);
            app.Correlation.MenuSelectedFcn = createCallbackFcn(app, @CorrelationMenuSelected, true);
            app.Correlation.Text = '相关性验证';
            app.Trendiness = uimenu(app.Data_Verification);
            app.Trendiness.MenuSelectedFcn = createCallbackFcn(app, @TrendinessMenuSelected, true);
            app.Trendiness.Text = '趋势性验证';
            app.Data_Interpolation = uimenu(app.mainapp);
            app.Data_Interpolation.MenuSelectedFcn = createCallbackFcn(app, @Data_InterpolationMenuSelected, true);
            app.Data_Interpolation.Text = '数据插补';
            app.Data_Correction = uimenu(app.mainapp);
            app.Data_Correction.Text = '代表年订正';
            app.MCP = uimenu(app.Data_Correction);
            app.MCP.MenuSelectedFcn = createCallbackFcn(app, @MCPMenuSelected, true);
            app.MCP.Text = 'MCP';
            app.Analyse = uimenu(app.mainapp);
            app.Analyse.Text = '数据分析';
            app.Wind_Shear = uimenu(app.Analyse);
            app.Wind_Shear.MenuSelectedFcn = createCallbackFcn(app, @Wind_ShearMenuSelected, true);
            app.Wind_Shear.Text = '风廓线分析';
            app.Turbulence = uimenu(app.Analyse);
            app.Turbulence.MenuSelectedFcn = createCallbackFcn(app, @TurbulenceMenuSelected, true);
            app.Turbulence.Text = '湍流分析';
            app.TabGroup = uitabgroup(app.mainapp);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.Summary = uitab(app.TabGroup);
            app.Summary.Title = '数据概况';
            app.Sub_Summary = uipanel(app.Summary);
            app.Start_Time = uilabel(app.Sub_Summary);
            app.Start_Time.Text = '开始时间';
            app.Start_Time_Data = uilabel(app.Sub_Summary);
            app.Start_Time_Data.Text = '';
            app.End_Time = uilabel(app.Sub_Summary);
            app.End_Time.Text = '结束时间';
            app.End_Time_Data = uilabel(app.Sub_Summary);
            app.End_Time_Data.Text = '';
            app.Mean_Temp = uilabel(app.Sub_Summary);
            app.Mean_Temp.Text = '平均温度';
            app.Mean_Temp_Data = uilabel(app.Sub_Summary);
            app.Mean_Temp_Data.Text = '';
            app.Mean_BP = uilabel(app.Sub_Summary);
            app.Mean_BP.Text = '平均气压';
            app.Mean_BP_Data = uilabel(app.Sub_Summary);
            app.Mean_BP_Data.Text = '';
            app.Mean_WS_Highest = uilabel(app.Sub_Summary);
            app.Mean_WS_Highest.Text = '120m平均风速';
            app.Mean_WS_Highest_Data = uilabel(app.Sub_Summary);
            app.Mean_WS_Highest_Data.Text = '';
            app.Mean_WPD_50 = uilabel(app.Sub_Summary);
            app.Mean_WPD_50.Text = '50m风功率密度';
            app.Mean_WPD_50_Data = uilabel(app.Sub_Summary);
            app.Mean_WPD_50_Data.Text = '';
            app.Wind_Power_Class = uilabel(app.Sub_Summary);
            app.Wind_Power_Class.Text = '风功率等级';
            app.Wind_Power_Class_Data = uilabel(app.Sub_Summary);
            app.Wind_Power_Class_Data.Text = '';
            app.Power_Law = uilabel(app.Sub_Summary);
            app.Power_Law.Text = '风切变系数';
            app.Power_Law_Data = uilabel(app.Sub_Summary);
            app.Power_Law_Data.Text = '';
            app.Mean_rho = uilabel(app.Sub_Summary);
            app.Mean_rho.Text = '平均密度';
            app.Mean_rho_data = uilabel(app.Sub_Summary);
            app.Mean_rho_data.Text = '';
            app.Mon_WS_UIAxes = uiaxes(app.Summary);
            title(app.Mon_WS_UIAxes, '月平均风速曲线')
            xlabel(app.Mon_WS_UIAxes, '')
            ylabel(app.Mon_WS_UIAxes, '风速(m/s)')
            app.Wind_Rose = uitab(app.TabGroup);
            app.Wind_Rose.Title = '风玫瑰图';
            app.Label = uilabel(app.Wind_Rose);
            app.Label.Text = '数据类型';
            app.Data_Type = uidropdown(app.Wind_Rose);
            app.Data_Type.Items = {'风向玫瑰图', '风能玫瑰图'};
            app.Data_Type.ValueChangedFcn = createCallbackFcn(app, @Data_TypeValueChanged, true);
            app.Data_Type.Value = '风向玫瑰图';
            app.Label_2 = uilabel(app.Wind_Rose);
            app.Label_2.Text = '风速高度';
            app.WS_Height = uidropdown(app.Wind_Rose);
            app.WS_Height.Items = {'动态下拉框'};
            app.WS_Height.ValueChangedFcn = createCallbackFcn(app, @WS_HeightValueChanged, true);
            app.WS_Height.Value = '动态下拉框';
            app.Label_3 = uilabel(app.Wind_Rose);
            app.Label_3.Text = '风向高度';
            app.WD_Height = uidropdown(app.Wind_Rose);
            app.WD_Height.Items = {'动态下拉框'};
            app.WD_Height.ValueChangedFcn = createCallbackFcn(app, @WD_HeightValueChanged, true);
            app.WD_Height.Value = '动态下拉框';
            app.Time_Type = uibuttongroup(app.Wind_Rose);
            app.Time_Type.SelectionChangedFcn = createCallbackFcn(app, @Time_TypeSelectionChanged, true);
            app.Year_Data = uiradiobutton(app.Time_Type);
            app.Year_Data.Text = '年平均数据';
            app.Year_Data.Value = true;
            app.Month_Data = uiradiobutton(app.Time_Type);
            app.Month_Data.Text = '月平均数据';
            app.Rose = uipanel(app.Wind_Rose);
            app.UITable = uitable(app.Rose);
            app.UITable.ColumnName = {''; ''; ''; ''};
            app.UITable.RowName = {};
            app.Table_Title = uilabel(app.Rose);
            app.Table_Title.Text = '';
            app.Rose_Table = uibutton(app.Wind_Rose, 'state');
            app.Rose_Table.ValueChangedFcn = createCallbackFcn(app, @Rose_TableValueChanged, true);
            app.Rose_Table.Text = '图表转换';
            app.Diurnal_Profile = uitab(app.TabGroup);
            app.Diurnal_Profile.Title = '日变化曲线';
            app.Dirunal_Profile_Panel = uipanel(app.Diurnal_Profile);
            app.Time_Switch = uibuttongroup(app.Diurnal_Profile);
            app.Time_Switch.SelectionChangedFcn = createCallbackFcn(app, @Time_SwitchSelectionChanged, true);
            app.Year_WS_Diurnal = uiradiobutton(app.Time_Switch);
            app.Year_WS_Diurnal.Text = '年平均风速日变化';
            app.Year_WS_Diurnal.Value = true;
            app.Year_WPD_Diurnal = uiradiobutton(app.Time_Switch);
            app.Year_WPD_Diurnal.Text = '年平均风功率日变化';
            app.Mon_WS_Diurnal = uiradiobutton(app.Time_Switch);
            app.Mon_WS_Diurnal.Text = '月平均风速日变化';
            app.Mon_WPD_Diurnal = uiradiobutton(app.Time_Switch);
            app.Mon_WPD_Diurnal.Text = '月平均风功率日变化';
            app.Label_5 = uilabel(app.Diurnal_Profile);
            app.Label_5.Text = '风速高度';
            app.Diurnal_WS_Height = uidropdown(app.Diurnal_Profile);
            app.Diurnal_WS_Height.Items = {};
            app.Diurnal_WS_Height.ValueChangedFcn = createCallbackFcn(app, @Diurnal_WS_HeightValueChanged, true);
            app.Diurnal_WS_Height.Value = {};
            app.Histogram = uitab(app.TabGroup);
            app.Histogram.Title = '直方图';
            app.Histogram_Panel = uipanel(app.Histogram);
            app.Histogram_Table = uitable(app.Histogram_Panel);
            app.Histogram_Table.ColumnName = {'Column 1'; ''; ''; ''};
            app.Histogram_Table.RowName = {};
            app.Histogram_Table.Visible = 'off';
            app.Label_4 = uilabel(app.Histogram);
            app.Label_4.Text = '风速高度';
            app.Histogram_WS_Height = uidropdown(app.Histogram);
            app.Histogram_WS_Height.Items = {};
            app.Histogram_WS_Height.ValueChangedFcn = createCallbackFcn(app, @Histogram_WS_HeightValueChanged, true);
            app.Histogram_WS_Height.Value = {};
            app.Table_Switch = uibutton(app.Histogram, 'state');
            app.Table_Switch.ValueChangedFcn = createCallbackFcn(app, @Table_SwitchValueChanged, true);
            app.Table_Switch.Text = '图表转换';
            app.Weibull_Table = uitable(app.Histogram);
            app.Weibull_Table.ColumnName = {''; ''; ''; ''};
            app.Weibull_Table.RowName = {};
            % Show the figure after all components are created
            app.mainapp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.mainapp)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mainapp)
        end
    end
end