classdef rationality_verification < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        rationality_app    matlab.ui.Figure
        Label              matlab.ui.control.Label
        WS_Min             matlab.ui.control.NumericEditField
        EditField_9Label   matlab.ui.control.Label
        WS_Max             matlab.ui.control.NumericEditField
        Label_2            matlab.ui.control.Label
        WD_Min             matlab.ui.control.NumericEditField
        EditField_11Label  matlab.ui.control.Label
        WD_Max             matlab.ui.control.NumericEditField
        Label_4            matlab.ui.control.Label
        BP_Min             matlab.ui.control.NumericEditField
        EditField_15Label  matlab.ui.control.Label
        BP_Max             matlab.ui.control.NumericEditField
        WS_Lable           matlab.ui.control.Label
        WD_Lable           matlab.ui.control.Label
        BP_Lable           matlab.ui.control.Label
        Panel              matlab.ui.container.Panel
        UITable            matlab.ui.control.Table
        Caculate           matlab.ui.control.Button
        Error_WS_Lable     matlab.ui.control.Label
        Error_WD_Lable     matlab.ui.control.Label
        Error_BP_Lable     matlab.ui.control.Label
        Inter_P1           matlab.ui.control.Button
        Data_Update        matlab.ui.control.Button
        Close              matlab.ui.control.Button
    end


    properties (Access = private)
        CallingApp      % 主程序
        WS      %访问主程序风速
        WD      %访问主程序风向
        Temp    %访问主程序温度
        BP      %访问主程序气压
        All_Data    %拼接数据
        WS_Height 
        WD_Height
        Temp_Height
        BP_Height 
        WS_size 
        WD_size
        Temp_size
        BP_size
    end
    
    methods (Access = private)
        
        function Data_Setup(app)
            
            app.Caculate.Tooltip = "查询不合理数据";
            app.Inter_P1.Tooltip = "采用前后平均法插补数据";
            app.Data_Update.Tooltip = "插补后数据更新";
            app.Close.Tooltip = "关闭子程序，并将数据更新到主程序";
            app.WS = app.CallingApp.WS_Hour;
            app.WD = app.CallingApp.WD_Hour;
            app.Temp = app.CallingApp.Temp_Hour;
            app.BP = app.CallingApp.BP_Hour;
            app.All_Data = [app.WS{:,2:end},app.WD{:,2:end},app.Temp{:,2:end},app.BP{:,2:end}];      %拼接数据，不包含时间数据
            app.WS_Height = app.CallingApp.WS.Properties.CustomProperties.height;
            app.WD_Height = app.CallingApp.WD.Properties.CustomProperties.height;       %风速与风向的高度属性
            app.Temp_Height = app.CallingApp.Temp.Properties.CustomProperties.height;
            app.BP_Height = app.CallingApp.BP.Properties.CustomProperties.height;
            app.WS_size = size(app.CallingApp.WS_Hour);
            app.WD_size = size(app.CallingApp.WD_Hour);
            app.Temp_size = size(app.CallingApp.Temp_Hour);
            app.BP_size = size(app.CallingApp.BP_Hour);
        end
        function Table_Setup(app)
            %*************************表格初始化*******************************%
            app.UITable.Visible = 'on';     %表格状态为可见
            app.UITable.Data = [];      %删除表格数据
            app.UITable.UserData = [];      %清空标记数据
            s = uistyle("BackgroundColor",'white');
            addStyle(app.UITable,s);
            
            if app.WS_size(2) > 1
                ColumnName(1:app.WS_size(2) - 1) = strcat("Speed ", string(app.WS_Height(2:end)),"m");
            end
            if app.WD_size(2) > 1
                ColumnName(app.WS_size(2):app.WS_size(2) - 1 + app.WD_size(2) - 1) = strcat("Direction ",string(app.WD_Height(2:end)),"m");
            end
            if app.Temp_size(2) > 1
                ColumnName(app.WS_size(2) - 1 + app.WD_size(2) : app.WS_size(2) - 1 + app.WD_size(2) - 1 + app.Temp_size(2) - 1) ...
                    = strcat("Temp ",string(app.Temp_Height(2:end)),"m");
            end
            if app.BP_size(2) > 1
                ColumnName(app.WS_size(2) - 1 + app.WD_size(2) - 1 + app.Temp_size(2) ...
                    : app.WS_size(2) - 1 + app.WD_size(2) - 1 + app.Temp_size(2) - 1 + app.BP_size(2) - 1) = strcat("BP ",string(app.BP_Height(2:end)),"m");
            end
            app.UITable.ColumnName = cellstr(ColumnName);
            
            %**************************数据整合*******************************%
            [ws_row, ws_col] = Find_WS_Error(app);
            [wd_row, wd_col] = Find_WD_Error(app);
            [bp_row, bp_col] = Find_BP_Error(app);
            wd_col = wd_col + app.WS_size(2) - 1;   %wd_col的绝对位置为相对位置加风速数据列
            bp_col = bp_col + app.WS_size(2) -1 + app.WD_size(2) ;
            %*************获取不合理数据点****************%
            ws_row_size = size(ws_row);
            wd_row_size = size(wd_row);
            bp_row_size = size(bp_row);
            error_point = [ws_row_size(1),wd_row_size(1),bp_row_size(1)];
            %删除重复行
            ws_row = unique(ws_row);
            wd_row = unique(wd_row);
            bp_row = unique(bp_row);
            error_ws = app.All_Data(ws_row,:);
            error_wd = app.All_Data(wd_row,:);
            error_bp = app.All_Data(bp_row,:);
            time_ws = app.WS{ws_row,1};
            time_wd = app.WS{wd_row,1};
            time_bp = app.WS{bp_row,1};
            data = [error_ws;error_wd;error_bp];
            time = [time_ws;time_wd;time_bp];
            %************************************************%
            
            app.UITable.Data = data;
            app.UITable.RowName = cellstr(time);
            s = uistyle('HorizontalAlignment','left');
            addStyle(app.UITable,s);
            %不合理数据点数信息
            app.Error_WS_Lable.Text = strcat("风速不合理数据点数： ",string(error_point(1)));
            app.Error_WD_Lable.Text = strcat("风向不合理数据点数： ",string(error_point(2)));
            app.Error_BP_Lable.Text = strcat("气压不合理数据点数： ",string(error_point(3)));
            
            %标记数据
            Mark_Data(app);
        end

        function [row , col ] = Find_WS_Error(app)
            %找出数据集中的不合理数据，返回行数与列数
            data = app.WS{:,2:end};
            [row , col ] = find(data < app.WS_Min.Value | data > app.WS_Max.Value);
        end
    
        function [row , col ] = Find_WD_Error(app)
            data = app.WD{:,2:end};
            [row , col ] = find(data < app.WD_Min.Value | data > app.WD_Max.Value);
        end

        function [row , col] = Find_BP_Error(app)
            data = app.BP{:,2:end};
            [row , col] = find(data < app.BP_Min.Value | data > app.BP_Max.Value);
        end
        
        function Mark_Data(app)
            %将不合理数据在程序的表格界面中标记出来
            ws_col = 1:app.WS_size(2) - 1;
            wd_col = app.WS_size(2) : app.WS_size(2) - 1 + app.WD_size(2) - 1;
            temp_col = app.WS_size(2) - 1 + app.WD_size(2)  : app.WS_size(2) - 1 + app.WD_size(2) - 1 + app.Temp_size(2) - 1;
            bp_col =  app.WS_size(2) - 1 + app.WD_size(2) - 1 + app.Temp_size(2) : app.WS_size(2) - 1 + app.WD_size(2) - 1 + app.Temp_size(2) - 1 + app.BP_size(2) - 1;
            ws_data = app.UITable.Data(:,ws_col);
            wd_data = app.UITable.Data(:,wd_col);
            bp_data = app.UITable.Data(:,bp_col);
            [mark_ws_row,mark_ws_col] = find(ws_data < app.WS_Min.Value | ws_data > app.WS_Max.Value);
            [mark_wd_row,mark_wd_col] = find(wd_data < app.WD_Min.Value | wd_data > app.WD_Max.Value);
            [mark_bp_row,mark_bp_col] = find(bp_data < app.BP_Min.Value | bp_data > app.BP_Max.Value);            
            mark_wd_col = mark_wd_col + app.WS_size(2) - 1;     %相对位置转为绝对位置
            mark_bp_col = mark_bp_col + app.WS_size(2) - 1 + app.WD_size(2) - 1 + app.Temp_size(2) - 1;
            s = uistyle('BackgroundColor','red');       %将表格中不合理数据的backgroundcolor设置为red
            ws_size = size(mark_ws_row);
            wd_size = size(mark_wd_row);
            bp_size = size(mark_bp_row);
            if ws_size(1) == 1
                mark_ws_row = mark_ws_row';     %当数据只有一行时，find函数得到的结果是向量，需要将其转置为矩阵
                mark_ws_col = mark_ws_col';
            end
            if wd_size(1) == 1
                mark_wd_row = mark_wd_row';    
                mark_wd_col = mark_wd_col';
            end
            if bp_size(1) == 1
                mark_bp_row = mark_bp_row';    
                mark_bp_col = mark_bp_col';
            end
            try         %当要标记的索引为0时，不执行标记操作
                addStyle(app.UITable,s,'cell',[mark_ws_row,mark_ws_col]);     
                addStyle(app.UITable,s,'cell',[mark_wd_row,mark_wd_col]);
                addStyle(app.UITable,s,'cell',[mark_bp_row,mark_bp_col]);
            catch
            end
        end
        
        function Interpolation_Data(app)
            %将插值数据存入到app.All_Data中
            [ws_row, ws_col] = Find_WS_Error(app);
            [wd_row, wd_col] = Find_WD_Error(app);
            [bp_row, bp_col] = Find_BP_Error(app);
            ws_size = size(ws_row);
            wd_size = size(wd_row);
            bp_size = size(bp_row);
            wd_col = wd_col + app.WS_size(2) - 1;   %wd_col的绝对位置为相对位置加风速数据列
            bp_col = bp_col + app.WS_size(2) -1 + app.WD_size(2) ;
            %用circshift将数据往前和往后移动1位，相加就得到线性插值
            if ws_size(1) > 0
                for index = 2:ws_size(1) - 1    %   第一位数据与最后一位数据无法进行插值，故索引从2到end-1
                    app.All_Data(ws_row(index),ws_col(index)) = interp1([app.All_Data(ws_row(index) - 1,ws_col(index)),app.All_Data(ws_row(index) + 1,ws_col(index))],1.5);
                end
            end
            if wd_size(1) > 0
                for index = 2:wd_size(1) - 1
                    app.All_Data(wd_row(index),wd_col(index)) = interp1([app.All_Data(wd_row(index) - 1,wd_col(index)),app.All_Data(wd_row(index) + 1,wd_col(index))],1.5);
                end
            end
            if bp_size(1) > 0
                for index = 2:bp_size(1) - 1
                    app.All_Data(bp_row(index),bp_col(index)) = interp1([app.All_Data(bp_row(index) - 1,bp_col(index)),app.All_Data(bp_row(index) + 1,bp_col(index))],1.5);
                end
            end
        end
        
        function Transfor_Data(app)
            %将数据传回主程序并关闭子程序
            app.CallingApp.WS_Hour = app.WS;
            app.CallingApp.WD_Hour = app.WD;
            app.CallingApp.BP_Hour = app.BP;
            delete(app.rationality_app);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % 绑定主函数
            app.CallingApp = mainapp;   %适用app.CallingApp.访问主程序数据
            app.UITable.Visible = 'off';    %隐藏表格
            Data_Setup(app);
        end

        % Button pushed function: Caculate
        function CaculateButtonPushed(app, event)
            %当按下计算按钮时，执行以下程序
            Table_Setup(app);  
            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;

        end

        % Button pushed function: Inter_P1
        function Inter_P1ButtonPushed(app, event)
          Interpolation_Data(app);      %执行线性插补程序
        end

        % Button pushed function: Data_Update
        function Data_UpdateButtonPushed(app, event)
            %当点击完成后，将更改的数据保存到各自的数据列中
            ws_size = size(app.WS);
            wd_size = size(app.WD);
            bp_size = size(app.BP);
            app.WS(:,2:ws_size(2)) = array2table(app.All_Data(:,1:ws_size(2) - 1));
            app.WD(:,2:wd_size(2)) = array2table(app.All_Data(:,ws_size(2) : ws_size(2) + wd_size(2) - 1 - 1));
            app.BP(:,2:bp_size(2)) = array2table(app.All_Data(:,ws_size(2) + wd_size(2) - 1 + bp_size(2) - 1 : end));
            
            Table_Setup(app);       %将更新后的数据绘制于表格中
        end

        % Button pushed function: Close
        function CloseButtonPushed(app, event)
            %关闭子程序
            Transfor_Data(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.rationality_app = uifigure('Visible', 'off');
            app.rationality_app.Name = '合理性验证';
            app.Label = uilabel(app.rationality_app);
            app.Label.Text = '风速合理区间：最小值';
            app.WS_Min = uieditfield(app.rationality_app, 'numeric');
            app.EditField_9Label = uilabel(app.rationality_app);
            app.EditField_9Label.Text = '最大值';
            app.WS_Max = uieditfield(app.rationality_app, 'numeric');
            app.WS_Max.Value = 40;
            app.Label_2 = uilabel(app.rationality_app);
            app.Label_2.Text = '风向合理区间：最小值';
            app.WD_Min = uieditfield(app.rationality_app, 'numeric');
            app.EditField_11Label = uilabel(app.rationality_app);
            app.EditField_11Label.Text = '最大值';
            app.WD_Max = uieditfield(app.rationality_app, 'numeric');
            app.WD_Max.Value = 360;
            app.Label_4 = uilabel(app.rationality_app);
            app.Label_4.Text = '气压合理区间：最小值';
            app.BP_Min = uieditfield(app.rationality_app, 'numeric');
            app.BP_Min.Value = 94;
            app.EditField_15Label = uilabel(app.rationality_app);
            app.EditField_15Label.Text = '最大值';
            app.BP_Max = uieditfield(app.rationality_app, 'numeric');
            app.BP_Max.Value = 106;
            app.WS_Lable = uilabel(app.rationality_app);
            app.WS_Lable.Text = 'm/s';
            app.WD_Lable = uilabel(app.rationality_app);
            app.WD_Lable.Text = '°';
            app.BP_Lable = uilabel(app.rationality_app);
            app.BP_Lable.Text = 'kPa';
            app.Panel = uipanel(app.rationality_app);
            app.UITable = uitable(app.Panel);
            app.UITable.ColumnName = {''; ''; ''; ''};
            app.UITable.RowName = {''};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.Caculate = uibutton(app.rationality_app, 'push');
            app.Caculate.ButtonPushedFcn = createCallbackFcn(app, @CaculateButtonPushed, true);
            app.Caculate.Text = '查        询';
            app.Error_WS_Lable = uilabel(app.rationality_app);
            app.Error_WS_Lable.Text = '';
            app.Error_WD_Lable = uilabel(app.rationality_app);
            app.Error_WD_Lable.Text = '';
            app.Error_BP_Lable = uilabel(app.rationality_app);
            app.Error_BP_Lable.Text = '';
            app.Inter_P1 = uibutton(app.rationality_app, 'push');
            app.Inter_P1.ButtonPushedFcn = createCallbackFcn(app, @Inter_P1ButtonPushed, true);
            app.Inter_P1.Text = '线性插值';
            app.Data_Update = uibutton(app.rationality_app, 'push');
            app.Data_Update.ButtonPushedFcn = createCallbackFcn(app, @Data_UpdateButtonPushed, true);
            app.Data_Update.Text = '数据更新';
            app.Close = uibutton(app.rationality_app, 'push');
            app.Close.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.Close.Text = '完        成';
            % Show the figure after all components are created
            app.rationality_app.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = rationality_verification(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.rationality_app)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.rationality_app)
        end
    end
end