classdef trendiness_verification < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        trendiness_app    matlab.ui.Figure
        Label             matlab.ui.control.Label
        WS_Max            matlab.ui.control.NumericEditField
        msLabel           matlab.ui.control.Label
        Label_2           matlab.ui.control.Label
        Temp_Max          matlab.ui.control.NumericEditField
        Label_4           matlab.ui.control.Label
        Label_3           matlab.ui.control.Label
        BP_Max            matlab.ui.control.NumericEditField
        kpaLabel          matlab.ui.control.Label
        Panel             matlab.ui.container.Panel
        UITable           matlab.ui.control.Table
        Error_WS_Lable    matlab.ui.control.Label
        Error_Temp_Lable  matlab.ui.control.Label
        Error_BP_Lable    matlab.ui.control.Label
        WS_Height         matlab.ui.control.DropDown
        Temp_Height       matlab.ui.control.DropDown
        BP_Height         matlab.ui.control.DropDown
        Search            matlab.ui.control.Button
        Inter_P1          matlab.ui.control.Button
        Data_Update       matlab.ui.control.Button
        Close             matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp       % 主程序
        WS
        WD
        Temp
        BP
        WS_size
        WD_size
        Temp_size
        BP_size
        WS_Height_Info
        WD_Height_Info
        Temp_Height_Info
        BP_Height_Info
        All_Data
        ws_error_point      %风速错误点数
        temp_error_point
        bp_error_point
    end
    
    methods (Access = private)
        
        function UI_Setup(app)
            %界面初始化
            app.WS_Height.Items = strcat(string(app.WS_Height_Info(2:end))," m");
            app.Temp_Height.Items =strcat(string(app.Temp_Height_Info(2:end))," m");
            app.BP_Height.Items = strcat(string(app.BP_Height_Info(2:end))," m");
            
        end
        
        function Data_Setup(app)
            app.WS = app.CallingApp.WS_Hour;
            app.WD = app.CallingApp.WD_Hour;
            app.Temp = app.CallingApp.Temp_Hour;
            app.BP = app.CallingApp.BP_Hour;
            app.WS_size = size(app.WS);
            app.WD_size = size(app.WD);
            app.Temp_size = size(app.Temp);
            app.BP_size = size(app.BP);
            app.WS_Height_Info = app.CallingApp.WS.Properties.CustomProperties.height;
            app.WD_Height_Info = app.CallingApp.WD.Properties.CustomProperties.height;
            app.Temp_Height_Info = app.CallingApp.Temp.Properties.CustomProperties.height;
            app.BP_Height_Info = app.CallingApp.BP.Properties.CustomProperties.height;
            app.All_Data = [app.WS{:,2:end},app.WD{:,2:end},app.Temp{:,2:end},app.BP{:,2:end}];      %拼接数据，不包含时间数据
        end
        
        function [row,col] = Find_WS_Error(app)
            %查找风速不合理数据
            ws_height = split(app.WS_Height.Value,' ');
            for index = 2:app.WS_size(2)
                if isequal(app.WS_Height_Info(index),str2double(ws_height{1}))
                    ws_data = app.WS(:,index);
                end
            end
            ws_data = table2array(ws_data);
            diff_ws_data = abs(diff(ws_data));     
            [row,col] = find(diff_ws_data > app.WS_Max.Value );     %如果出现差值大于基准值，标记两个数据
            row = unique([row;row + 1],"sort");
        end
        
        function [row,col] = Find_Temp_Error(app)
            %查找温度不合理数据
            temp_height = split(app.Temp_Height.Value,' ');
            for index = 2:app.Temp_size(2)
                if isequal(app.Temp_Height_Info(index),str2double(temp_height{1}))
                    temp_data = app.Temp(:,index);
                end
            end
            temp_data = table2array(temp_data);
            diff_temp_data = abs(diff(temp_data));
            [row,col] = find(diff_temp_data > app.Temp_Max.Value);     %如果出现差值大于基准值，标记两个数据
            row = unique([row;row + 1],"sort");
        end
        
        function [row,col] = Find_BP_Error(app)
            %查找气压不合理数据
            bp_height = split(app.BP_Height.Value,' ');
            for index = 2:app.BP_size(2)
                if isequal(app.BP_Height_Info(index),str2double(bp_height{1}))
                    bp_data = app.BP(:,index);
                end
            end
            bp_data = table2array(bp_data);
            diff_bp_data = abs(diff(bp_data));
            [row,col] = find(diff_bp_data > app.BP_Max.Value );     %如果出现差值大于基准值，标记两个数据
            row = unique([row;row + 1],"sort");
        end


        function Table_Setup(app)
            %*************************表格初始化*******************************%
            app.UITable.Visible = 'on';     %表格状态为可见
            app.UITable.Data = [];      %删除表格数据
            app.UITable.UserData = [];      %清空标记数据
            s = uistyle("BackgroundColor",'white');
            addStyle(app.UITable,s);
            if app.WS_size(2) > 1
                ColumnName(1:app.WS_size(2) - 1) = strcat("Speed ", string(app.WS_Height_Info(2:end)),"m");
            end
            if app.WD_size(2) > 1
                ColumnName(app.WS_size(2):app.WS_size(2) - 1 + app.WD_size(2) - 1) = strcat("Direction ",string(app.WD_Height_Info(2:end)),"m");
            end
            if app.Temp_size(2) > 1
                ColumnName(app.WS_size(2) - 1 + app.WD_size(2) : app.WS_size(2) - 1 + app.WD_size(2) - 1 + app.Temp_size(2) - 1) ...
                    = strcat("Temp ",string(app.Temp_Height_Info(2:end)),"m");
            end
            if app.BP_size(2) > 1
                ColumnName(app.WS_size(2) - 1 + app.WD_size(2) - 1 + app.Temp_size(2) ...
                    : app.WS_size(2) - 1 + app.WD_size(2) - 1 + app.Temp_size(2) - 1 + app.BP_size(2) - 1) = strcat("BP ",string(app.BP_Height_Info(2:end)),"m");
            end
            app.UITable.ColumnName = cellstr(ColumnName);
            
            %**************************数据整合*******************************%
            ws_row = [];
            temp_row = [];
            bp_row = [];
            if app.WS_size(2) > 1
                [ws_row, ~] = Find_WS_Error(app);
            end
            if app.Temp_size(2) > 1
                [temp_row, ~] = Find_Temp_Error(app);
            end
            if app.BP_size(2) > 1
                [bp_row, ~] = Find_BP_Error(app);
            end
            row = unique([ws_row;temp_row;bp_row],"sort");
            %*************获取不合理数据点****************%
            data = app.All_Data(row,:);
            time = app.WS{row,1};
            %************************************************%
            app.UITable.Data = data;
            app.UITable.RowName = cellstr(time);
            s = uistyle('HorizontalAlignment','left');
            addStyle(app.UITable,s);
            Mark_Data(app);
            if app.WS_size(2) > 1
                app.Error_WS_Lable.Text = strcat("风速不合理数据点数： ",string(app.ws_error_point));
            end
            if app.Temp_size(2) > 1
                app.Error_Temp_Lable.Text = strcat("温度不合理数据点数： ",string(app.temp_error_point));
            end
            if app.BP_size(2) > 1
                app.Error_BP_Lable.Text = strcat("气压不合理数据点数： ",string(app.bp_error_point));
            end  
        end
        
        
        function Mark_Data(app)
            %标记不合理数据
            ws_height = split(app.WS_Height.Value,' ');
            temp_height = split(app.Temp_Height.Value,' ');
            bp_height = split(app.BP_Height.Value,' ');
            ws_index = 0;       %索引初始化
            temp_index = 0;
            bp_index = 0;
            for index = 2:app.WS_size(2)
                if isequal(app.WS_Height_Info(index),str2double(ws_height{1}))
                    ws_index = index - 1;
                end
            end
            
            for index = 2:app.Temp_size(2)
                if isequal(app.Temp_Height_Info(index),str2double(temp_height{1}))
                    temp_index = index - 1;
                end
            end
            for index = 2:app.BP_size(2)
                if isequal(app.BP_Height_Info(index),str2double(bp_height{1}))
                    bp_index = index - 1;
                end
            end
            
            mark_ws_col = ws_index;      %数据列号
            mark_temp_col = temp_index + app.WS_size(2) - 1 + app.WD_size(2) - 1;
            mark_bp_col = bp_index + app.WS_size(2) - 1 + app.WD_size(2) - 1 + app.Temp_size(2) - 1;
            %从UITable中获取数据
            ws_data = app.UITable.Data(:,mark_ws_col);
            temp_data = app.UITable.Data(:,mark_temp_col);
            bp_data = app.UITable.Data(:,mark_bp_col);
            %**************************%
            time_data = app.UITable.RowName;
            time_data = datetime(time_data);
            diff_time = caldiff(time_data);
            t1 = datetime(0,0,0,1,0,0);
            t2 = datetime(0,0,0,2,0,0);
            diff_hour = between(t1,t2);     %创建一个相差一小时的时间基准值
            time_size = size(diff_time);
            diff_ws_data = abs(diff(ws_data));
            diff_temp_data = abs(diff(temp_data));
            diff_bp_data = abs(diff(bp_data));          %获取表内数据差值
            is_continuous = zeros(1,time_size(1));     %判断时间是否连续的数组，若连续为1，否则为0
            is_ws_mark = zeros(1,time_size(1));         %判断数据是否需要标记
            is_temp_mark = zeros(1,time_size(1));
            is_bp_mark = zeros(1,time_size(1));
            for index = 1:time_size(1) 
                is_continuous(index) = isequal(diff_time(index),diff_hour);
                is_ws_mark(index) = (diff_ws_data(index) > app.WS_Max.Value && is_continuous(index));
                is_temp_mark(index) = (diff_temp_data(index) > app.Temp_Max.Value && is_continuous(index));
                is_bp_mark(index) = (diff_bp_data(index) > app.BP_Max.Value && is_continuous(index));
            end
            is_ws_mark = is_ws_mark';
            is_temp_mark = is_temp_mark';
            is_bp_mark = is_bp_mark';
            %当表内数据差值复合要求且时间相连时，标记数据
            [ws_row,~] = find(is_ws_mark== 1);     %如果出现差值大于基准值，标记两个数据
            mark_ws_row =ws_row;
            mark_ws_row = unique([mark_ws_row;mark_ws_row + 1]);
            mark_ws_size = size(mark_ws_row);       %标记数量
            app.ws_error_point = mark_ws_size(1);
            [temp_row,~] = find(is_temp_mark == 1);     %如果出现差值大于基准值，标记两个数据
            mark_temp_row = temp_row;
            mark_temp_row = unique([mark_temp_row;mark_temp_row + 1]);
            mark_temp_size = size(mark_temp_row);       %标记数量
            app.temp_error_point = mark_temp_size(1);

            [bp_row,~] = find(is_bp_mark == 1);     %如果出现差值大于基准值，标记两个数据
            mark_bp_row = bp_row;
            mark_bp_row = unique([mark_bp_row;mark_bp_row + 1]);
            mark_bp_size = size(mark_bp_row);       %标记数量
            app.bp_error_point = mark_bp_size(1);
            ws_size = size(mark_ws_row);
            temp_size = size(mark_temp_row);
            bp_size = size(mark_bp_row);
            s = uistyle('BackgroundColor','red');
            if app.WS_size(2) > 1
                addStyle(app.UITable,s,'cell',[mark_ws_row,repelem(mark_ws_col,ws_size(1))']);
            end
            if app.Temp_size(2) > 1
                addStyle(app.UITable,s,'cell',[mark_temp_row,repelem(mark_temp_col,temp_size(1))']);
            end
            if app.BP_size(2) > 1
                addStyle(app.UITable,s,'cell',[mark_bp_row,repelem(mark_bp_col,bp_size(1))']);
            end
        end
        
        function Interpolation_Data(app)
            %线性插值
            ws_height = split(app.WS_Height.Value,' ');
            for index = 2:app.WS_size(2)
                if isequal(app.WS_Height_Info(index),str2double(ws_height{1}))
                    ws_column = index - 1;
                end
            end
            temp_height = split(app.Temp_Height.Value,' ');
            for index = 2:app.Temp_size(2)
                if isequal(app.Temp_Height_Info(index),str2double(temp_height{1}))
                    temp_column = index - 1 + app.WS_size(2) - 1 + app.WD_size(2) - 1;
                end
            end
            bp_height = split(app.BP_Height.Value,' ');
            for index = 2:app.BP_size(2)
                if isequal(app.BP_Height_Info(index),str2double(bp_height{1}))
                    bp_column = index - 1 + app.WS_size(2) - 1 + app.WD_size(2) - 1 + app.Temp_size(2) - 1;
                end
            end
            
            %获取标记数据

            if app.WS_size(2) > 1
                [ws_row,ws_col] = Find_WS_Error(app);
                ws_data = app.All_Data(:,ws_column);
                pre_ws_data = circshift(ws_data,-1);
                next_ws_data = circshift(ws_data,1);
                for index = ws_row'
                    app.All_Data(index,ws_column) = interp1([pre_ws_data(index),next_ws_data(index)],1.5);
                end
                next_ws_data = circshift(ws_data,1);
            end
            if app.Temp_size(2) > 1
                [temp_row,temp_col] = Find_Temp_Error(app);
                temp_data = app.All_Data(:,temp_column);
                pre_temp_data = circshift(temp_data,-1);
                next_temp_data = circshift(temp_data,1);
                for index = temp_row'
                    app.All_Data(index,temp_column) = interp1([pre_temp_data(index),next_temp_data(index)],1.5);
                end
            end
            if app.BP_size(2) > 1
                [bp_row,bp_col] = Find_BP_Error(app);       %不合理数据的位置
                bp_data = app.All_Data(:,bp_column);
                pre_bp_data = circshift(bp_data,-1);
                next_bp_data = circshift(bp_data,1);
                for index = bp_row'
                    app.All_Data(index,bp_column) = interp1([pre_bp_data(index),next_bp_data(index)],1.5);
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
            app.UITable.Visible = 'off';        %隐藏图表
            Data_Setup(app);
            UI_Setup(app);
        end

        % Button pushed function: Search
        function SearchButtonPushed(app, event)
            Table_Setup(app);
        end

        % Button pushed function: Inter_P1
        function Inter_P1ButtonPushed(app, event)
            Interpolation_Data(app);
        end

        % Button pushed function: Data_Update
        function Data_UpdateButtonPushed(app, event)
            ws_size = size(app.WS);
            wd_size = size(app.WD);
            temp_size = size(app.Temp);
            bp_size = size(app.BP);
            app.WS(:,2:ws_size(2)) = array2table(app.All_Data(:,1:ws_size(2) - 1));
            app.Temp(:,2:temp_size(2)) = array2table(app.All_Data(:,ws_size(2) - 1 + wd_size(2) ...
                : ws_size(2) - 1 + wd_size(2) - 1 + temp_size(2) - 1));
            app.BP(:,2:bp_size(2)) = array2table(app.All_Data(:,ws_size(2) - 1 + wd_size(2) - 1 + temp_size(2) ...
                : ws_size(2) - 1 + wd_size(2) - 1 + temp_size(2) - 1 + bp_size(2) - 1));
            Table_Setup(app);       %将更新后的数据绘制于表格中
        end

        % Button pushed function: Close
        function CloseButtonPushed(app, event)
            %关闭子程序
            app.CallingApp.WS_Hour = app.WS;
            app.CallingApp.Temp_Hour = app.Temp;
            app.CallingApp.BP_Hour = app.BP;
            delete(app.trendiness_app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.trendiness_app = uifigure('Visible', 'off');
            app.trendiness_app.Name = '趋势性验证';
            app.Label = uilabel(app.trendiness_app);
            app.Label.Text = '1小时平均风速变化小于';
            app.WS_Max = uieditfield(app.trendiness_app, 'numeric');
            app.WS_Max.Value = 6;
            app.msLabel = uilabel(app.trendiness_app);
            app.msLabel.Text = 'm/s';
            app.Label_2 = uilabel(app.trendiness_app);
            app.Label_2.Text = '1小时平均温度变化小于';
            app.Temp_Max = uieditfield(app.trendiness_app, 'numeric');
            app.Temp_Max.Value = 5;
            app.Label_4 = uilabel(app.trendiness_app);
            app.Label_4.Text = '℃';
            app.Label_3 = uilabel(app.trendiness_app);
            app.Label_3.Text = '1小时平均气压变化小于';
            app.BP_Max = uieditfield(app.trendiness_app, 'numeric');
            app.BP_Max.Value = 1;
            app.kpaLabel = uilabel(app.trendiness_app);
            app.kpaLabel.Text = 'kpa';
            app.Panel = uipanel(app.trendiness_app);
            app.UITable = uitable(app.Panel);
            app.UITable.ColumnName = {''; ''; ''; ''};
            app.UITable.RowName = {};
            app.Error_WS_Lable = uilabel(app.trendiness_app);
            app.Error_WS_Lable.Text = '';
            app.Error_Temp_Lable = uilabel(app.trendiness_app);
            app.Error_Temp_Lable.Text = '';
            app.Error_BP_Lable = uilabel(app.trendiness_app);
            app.Error_BP_Lable.Text = '';
            app.WS_Height = uidropdown(app.trendiness_app);
            app.WS_Height.Items = {};
            app.WS_Height.Value = {};
            app.Temp_Height = uidropdown(app.trendiness_app);
            app.Temp_Height.Items = {};
            app.Temp_Height.Value = {};
            app.BP_Height = uidropdown(app.trendiness_app);
            app.BP_Height.Items = {};
            app.BP_Height.Value = {};
            app.Search = uibutton(app.trendiness_app, 'push');
            app.Search.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.Search.Text = '查        询';
            app.Inter_P1 = uibutton(app.trendiness_app, 'push');
            app.Inter_P1.ButtonPushedFcn = createCallbackFcn(app, @Inter_P1ButtonPushed, true);
            app.Inter_P1.Text = '线性插值';
            app.Data_Update = uibutton(app.trendiness_app, 'push');
            app.Data_Update.ButtonPushedFcn = createCallbackFcn(app, @Data_UpdateButtonPushed, true);
            app.Data_Update.Text = '数据更新';
            app.Close = uibutton(app.trendiness_app, 'push');
            app.Close.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.Close.Text = '完        成';
            % Show the figure after all components are created
            app.trendiness_app.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = trendiness_verification(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.trendiness_app)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.trendiness_app)
        end
    end
end