classdef turbulence_analyze < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        turbulence_app  matlab.ui.Figure
        UIAxes          matlab.ui.control.UIAxes
        UITable         matlab.ui.control.Table
    end

    
    properties (Access = private)
         CallingApp     %主程序
         WS
         SD
         WS_size
         SD_size
         WS_Height
         SD_Height
         TI     %湍流强度
         TI_15      %15m/s时湍流强度
    end
    
    methods (Access = private)
        
        function Data_Setup(app)
            app.WS = app.CallingApp.WS;
            app.SD = app.CallingApp.SD;
            app.WS_Height = app.CallingApp.WS.Properties.CustomProperties.height;
            app.SD_Height = app.CallingApp.SD.Properties.CustomProperties.height;
            app.WS_size = size(app.WS);
            app.SD_size = size(app.SD);
        end
        
        function UI_Setup(app)
            app.UIAxes.XLim = [0,inf];
            app.UIAxes.YLim = [0,max(app.WS_Height) + 20];
        end
        
        function Caculate_TI(app)
            %计算平均湍流强度及15m/s时平均湍流强度
            for index = 2:app.WS_size(2)
                ws_data = app.WS(:,index);
                sd_data = app.SD(:,index);
                ws_data = table2array(ws_data);
                sd_data = table2array(sd_data);
                row = find(ws_data == 15);
                app.TI_15(index - 1) = mean(sd_data(row)./ws_data(row));
                app.TI(index - 1) = mean(sd_data./ws_data);
            end
        end
        function Drow_Plot(app)
            height = app.WS_Height(2:end);
            scatter(app.UIAxes,round(app.TI,2),height);
        end
        
        function Drow_Table(app)
            app.UITable.ColumnName = ["平均湍流强度","15m/s平均湍流强度","IEC湍流强度等级"];
            app.UITable.ColumnFormat = {'numeric','numeric','char'};
            ti_size = size(app.TI_15);
            table_data = table('Size',[ti_size(2) 3],'VariableTypes',{'double','double','string'}); 
            %获取iec等级数据
            for index = 1:ti_size(2)
                if app.TI_15(index) > 0.16
                    iec_category(index) = 'S';
                elseif app.TI_15(index) > 0.14
                    iec_category(index) = 'A';
                elseif app.TI_15(index) > 0.12
                    iec_category(index) = 'B';
                elseif app.TI_15(index) >0
                    iec_category(index) = 'C';
                end
            end
            table_data(:,1) = table(round(app.TI',2));
            table_data(:,2) = table(round(app.TI_15',3));
            table_data(:,3) = table(string(iec_category'));
            app.UITable.Data = table_data;
            app.UITable.RowName = strcat("Speed ",string(app.WS_Height(2:end)),"m");
            s = uistyle('HorizontalAlignment','left');
            addStyle(app.UITable,s);
            legend(app.UIAxes,"实测数据");
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
            Data_Setup(app);
            Caculate_TI(app);
            UI_Setup(app);
            Drow_Plot(app);
            Drow_Table(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.turbulence_app = uifigure('Visible', 'off');
            app.turbulence_app.Name = '湍流分析';
            app.UIAxes = uiaxes(app.turbulence_app);
            title(app.UIAxes, '湍流强度分布')
            xlabel(app.UIAxes, '湍流强度')
            ylabel(app.UIAxes, '高度(m)')
            app.UITable = uitable(app.turbulence_app);
            app.UITable.ColumnName = {''; ''; ''; ''};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.turbulence_app.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = turbulence_analyze(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.turbulence_app)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.turbulence_app)
        end
    end
end