classdef wind_shear_analyze < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        wind_shear_app    matlab.ui.Figure
        UITable           matlab.ui.control.Table
        UIAxes            matlab.ui.control.UIAxes
        Wind_Shear_Lable  matlab.ui.control.Label
        Panel             matlab.ui.container.Panel
        Drow_Line         matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp   % 主函数
        WS
        Height
        WS_size
        CheckBox        %复选框
    end
    
    methods (Access = private)
        
        function Drow_Plot(app)
            %绘图更新前清空坐标区
            cla(app.UIAxes);
            data_index = [];
            %风切变系数计算公式为：U(z) = βz^α --> ln(U(z)) = αln(z) + ln(β)
            %alpha = fitresult.a,ln(β) = fitresult.
            for index = 1:app.WS_size(2) - 1
                check_box = app.CheckBox{:,index};
                value = check_box.Value;
                if value == 1
                    data_index(end + 1) = index;
                end
            end
            if (isempty(data_index))
                ws_data =  mean(table2array(app.WS(:,2:end)));
                height = app.Height(2:end);
            else
                ws_data =  mean(table2array(app.WS(:,data_index + 1)));
                height = app.Height(data_index + 1);
            end
            x_data = height;
            y_data = ws_data;
            scatter(app.UIAxes,y_data,x_data);
            hold(app.UIAxes,"on");
            app.UIAxes.XLim = [0,inf];
            app.UIAxes.YLim = [0,max(height) + 20];
            x_data = log(x_data);
            y_data = log(y_data);
            [xData, yData] = prepareCurveData( x_data, y_data );        %x_data为高度数据，y_data为风速数据
            ft = fittype( 'a*x + b', 'independent', 'x', 'dependent', 'y' );
            opts = fitoptions( 'Method', 'NonlinearLeastSquares' );
            try
            [fitresult, ~] = fit( xData, yData, ft, opts );        %fitresult存储拟合结果，gof存储相关系数
            catch
                errordlg("请选择两个或两个以上数据高度");
            end
            wind_shear = fitresult.a;
            app.Wind_Shear_Lable.Text = strcat("风切变系数：",string(wind_shear));
            %绘制拟合曲线
            y = 0:0.1:max(height) + 20;
            x = exp(fitresult.b).*(y.^fitresult.a);
            row = find(x ~=0);
            plot(app.UIAxes,x(row),y(row));
            legend(app.UIAxes,{"实测数据","拟合数据"});
            
        end
        
        function Drow_Table(app)
            for index = 2:app.WS_size(2)
                rowname(index - 1) = strcat("speed ",string(app.Height(index)),"m");
            end
            ws_data =  mean(table2array(app.WS(:,2:end)));
            app.UITable.Data = ws_data';
            app.UITable.ColumnName = {"平均风速(m/s)"};
            app.UITable.RowName = rowname';
            s = uistyle("HorizontalAlignment",'left');
            addStyle(app.UITable,s);
        end
        
        function Data_Setup(app)
            %数据初始化
            app.WS = app.CallingApp.WS_Hour;
            app.Height = app.CallingApp.WS.Properties.CustomProperties.height;
            app.WS_size = size(app.WS);
        end
        
        function UI_Setup(app)
            for index = 2:app.WS_size(2)
                check_box = uicheckbox(app.Panel);
                check_box.Position = [10,200 - (index - 2)*30,90,20];
                check_box.Text = strcat("Speed ",string(app.Height(index)),"m");
                app.CheckBox{index - 1} = check_box;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;       %绑定主函数
            Data_Setup(app);
            UI_Setup(app);
            Drow_Table(app);
            Drow_Plot(app);
        end

        % Button pushed function: Drow_Line
        function Drow_LinePushed(app, event)
        Drow_Plot(app);     %更新绘图

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.wind_shear_app = uifigure('Visible', 'off');
            app.wind_shear_app.Name = '风廓线分析';
            app.UITable = uitable(app.wind_shear_app);
            app.UITable.ColumnName = {''; ''; ''; ''};
            app.UITable.RowName = {};
            app.UIAxes = uiaxes(app.wind_shear_app);
            title(app.UIAxes, '风廓线')
            xlabel(app.UIAxes, '风速(m/s)')
            ylabel(app.UIAxes, '高度(m)')
            app.Wind_Shear_Lable = uilabel(app.wind_shear_app);
            app.Wind_Shear_Lable.Text = '风切变系数：';
            app.Panel = uipanel(app.wind_shear_app);
            app.Drow_Line = uibutton(app.Panel, 'push');
            app.Drow_Line.ButtonPushedFcn = createCallbackFcn(app, @Drow_LinePushed, true);
            app.Drow_Line.Text = '绘        制';
            % Show the figure after all components are created
            app.wind_shear_app.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = wind_shear_analyze(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.wind_shear_app)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.wind_shear_app)
        end
    end
end